classdef OpenMebius2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OpenMebius2UIFigure matlab.ui.Figure
        ApplicationMenu matlab.ui.container.Menu
        ReloadWindowMenu matlab.ui.container.Menu
        ClearcacheMenu matlab.ui.container.Menu
        PreferencesMenu matlab.ui.container.Menu
        ExperimentaldataMenu matlab.ui.container.Menu
        ExporttemplateExcelfileMenu matlab.ui.container.Menu
        FilesMenu matlab.ui.container.Menu
        ImportMSdatafromtextfilesMenu matlab.ui.container.Menu
        ModelMenu matlab.ui.container.Menu
        BatchMenu matlab.ui.container.Menu
        ViewMenu matlab.ui.container.Menu
        ViewReportMenu matlab.ui.container.Menu
        HelpMenu matlab.ui.container.Menu
        ViewlogsMenu matlab.ui.container.Menu
        OpenMebius2manualMenu matlab.ui.container.Menu
        AboutOpenMebius2Menu matlab.ui.container.Menu
        GridLayout matlab.ui.container.GridLayout
        GridLayout10 matlab.ui.container.GridLayout
        SubUIAxes matlab.ui.control.UIAxes
        MainUIAxes matlab.ui.control.UIAxes
        GridLayout9 matlab.ui.container.GridLayout
        LogTextArea matlab.ui.control.TextArea
        TabGroup matlab.ui.container.TabGroup
        StoichiometryTab matlab.ui.container.Tab
        GridLayout11 matlab.ui.container.GridLayout
        GridLayout12 matlab.ui.container.GridLayout
        ModelReloadButton matlab.ui.control.Button
        ModelEditButton matlab.ui.control.Button
        ModelSaveButton matlab.ui.control.Button
        ModelTable matlab.ui.control.Table
        MSTab matlab.ui.container.Tab
        GridLayout11_2 matlab.ui.container.GridLayout
        GridLayout13 matlab.ui.container.GridLayout
        AtomTable matlab.ui.control.Table
        MSTable matlab.ui.control.Table
        GridLayout12_2 matlab.ui.container.GridLayout
        MSReloadButton matlab.ui.control.Button
        MSEditButton matlab.ui.control.Button
        MSSaveButton matlab.ui.control.Button
        ExperimentTab matlab.ui.container.Tab
        GridLayout11_3 matlab.ui.container.GridLayout
        GridLayout13_2 matlab.ui.container.GridLayout
        ExpTable matlab.ui.control.Table
        GridLayout14 matlab.ui.container.GridLayout
        BiomassTable matlab.ui.control.Table
        GridLayout12_3 matlab.ui.container.GridLayout
        ExpCalculationButton matlab.ui.control.Button
        ExpImportButton matlab.ui.control.Button
        ExpReloadButton matlab.ui.control.Button
        ExpSaveButton matlab.ui.control.Button
        TracerTab matlab.ui.container.Tab
        GridLayout11_4 matlab.ui.container.GridLayout
        GridLayout15 matlab.ui.container.GridLayout
        LabelTable matlab.ui.control.Table
        UptakeTable matlab.ui.control.Table
        GridLayout12_4 matlab.ui.container.GridLayout
        TracerConfigButton matlab.ui.control.Button
        TracerReloadButton matlab.ui.control.Button
        TracerSaveButton matlab.ui.control.Button
        RunTab matlab.ui.container.Tab
        GridLayout11_5 matlab.ui.container.GridLayout
        GridLayout12_5 matlab.ui.container.GridLayout
        RunAutoButton matlab.ui.control.Button
        RunConfigButton matlab.ui.control.Button
        RunReloadButton matlab.ui.control.Button
        RunSaveButton matlab.ui.control.Button
        RunRunButton matlab.ui.control.Button
        RunTable matlab.ui.control.Table
        ResultTab matlab.ui.container.Tab
        GridLayout11_6 matlab.ui.container.GridLayout
        GridLayout15_2 matlab.ui.container.GridLayout
        ResultSubTable matlab.ui.control.Table
        ResultMainTable matlab.ui.control.Table
        GridLayout12_7 matlab.ui.container.GridLayout
        ResultDropDown matlab.ui.control.DropDown
        GridLayout12_6 matlab.ui.container.GridLayout
        ResultReportButton matlab.ui.control.Button
        ResultReloadButton matlab.ui.control.Button
        ResultSaveButton matlab.ui.control.Button
        GridLayout2 matlab.ui.container.GridLayout
        StatusHTML matlab.ui.control.HTML
        ProjectPanel matlab.ui.container.Panel
        GridLayout3 matlab.ui.container.GridLayout
        GridLayout16_2 matlab.ui.container.GridLayout
        TemplateModelBrowseButton matlab.ui.control.Button
        TemplateModelDirectoryDropDown matlab.ui.control.DropDown
        GridLayout16 matlab.ui.container.GridLayout
        ProjectBrowseButton matlab.ui.control.Button
        ProjectDirectoryDropDown matlab.ui.control.DropDown
        ProjectCreateButton matlab.ui.control.Button
        TemplateModelSaveButton matlab.ui.control.Button
        GridLayout5_3 matlab.ui.container.GridLayout
        TemplateModelLoadButton matlab.ui.control.Button
        ProjectLabel_2 matlab.ui.control.Label
        GridLayout5_2 matlab.ui.container.GridLayout
        ProjectSaveButton matlab.ui.control.Button
        GridLayout8 matlab.ui.container.GridLayout
        OrganismEditField matlab.ui.control.EditField
        OrganismEditFieldLabel matlab.ui.control.Label
        GridLayout7 matlab.ui.container.GridLayout
        ProjectAuthorEditField matlab.ui.control.EditField
        ProjectauthorEditFieldLabel matlab.ui.control.Label
        GridLayout5 matlab.ui.container.GridLayout
        ProjectLoadButton matlab.ui.control.Button
        GridLayout4 matlab.ui.container.GridLayout
        ProjectNameEditField matlab.ui.control.EditField
        ProjectnameEditFieldLabel matlab.ui.control.Label
        ProjectLabel matlab.ui.control.Label
        ContextMenu matlab.ui.container.ContextMenu
        AddLabelMenu matlab.ui.container.Menu
        RemoveLabelMenu matlab.ui.container.Menu
        ExperimentContextMenu matlab.ui.container.ContextMenu
        ViewMStableMenu matlab.ui.container.Menu
        ContextMenuRun matlab.ui.container.ContextMenu
        AddbatchMenu matlab.ui.container.Menu
        RemovethisbatchMenu matlab.ui.container.Menu
        ParallellabelingMenu matlab.ui.container.Menu
        ContextMenu2 matlab.ui.container.ContextMenu
        RelativetoMenu matlab.ui.container.Menu
        ContextMenuResultSelect matlab.ui.container.ContextMenu
        RangeplotMenu matlab.ui.container.Menu
        ViewsuggestionMenu matlab.ui.container.Menu
        ContextMenu3 matlab.ui.container.ContextMenu
        CopythistracerforallentriesMenu matlab.ui.container.Menu
    end

    properties (Access = public)

        calcStatus (4, 1) string {mustBeMember(calcStatus, ["init", "running", "finished", "error"])} = "init";
        typeSimulation (1, 1) string {mustBeMember(typeSimulation, ["MDV", "Flux", "Label"])} = "Flux";
        model;
        exp;
        batch;
        result;
        report;

        LabelConfigApp;
        TracerConfigApp;
        RunConfigApp;
        MSViewApp;
        ComparisonViewApp;
        RunAddBatchApp;
        ViewSuggestionApp;
        LogApp;
        ProgressBar CustomProgressBar

        % Styles
        styleError = uistyle('BackgroundColor', '#FFAABB');
        styleErrorDark = uistyle('BackgroundColor', '#332225');

        % Styles for results
        styleIsPassed = uistyle('FontColor', '#009E73', 'FontWeight', 'bold');
        styleIsNotPassed = uistyle('FontColor', '#D55E00', 'FontWeight', 'bold');
        styleIsPassedDark = uistyle('FontColor', '#004834', 'FontWeight', 'bold');
        styleIsNotPassedDark = uistyle('FontColor', '#331700', 'FontWeight', 'bold');

        % Table styles
        styleSuccessIcon = uistyle('Icon', 'success', 'IconAlignment', 'rightmargin');
        styleWarningIcon = uistyle('Icon', 'warning', 'IconAlignment', 'rightmargin');
        styleErrorIcon = uistyle('Icon', 'error', 'IconAlignment', 'rightmargin');
        styleQuestionIcon = uistyle('Icon', 'question', 'IconAlignment', 'rightmargin');
        styleInfoIcon = uistyle('Icon', 'info', 'IconAlignment', 'rightmargin');

    end % properties (Access = public)

    %% Private properties

    properties (Access = private)

        % Directory
        directoryModel (1, 1) string
        directoryExp (1, 1) string
        directoryResult (1, 1) string

        % Editable array for locking and unlocking
        ModelTableEditable logical
        MSTableEditable logical
        AtomTableEditable logical
        BiomassTableEditable logical
        TracerTableEditable logical
        UptakeTableEditable logical
        RunTableEditable logical
        ResultMainTableEditable logical
        ResultSubTableEditable logical

    end % properties (Access=private)

    properties (Access = private)

        Presenter openmebius.presentation.main.MainPresenter
        BatchPresenter openmebius.presentation.batch.BatchPresenter
        ExperimentPresenter openmebius.presentation.experiment.ExperimentPresenter
        ResultPresenter openmebius.presentation.result.ResultPresenter
        ResultPlotPresenter openmebius.presentation.result.ResultPlotPresenter
        DialogService openmebius.presentation.dialog.AppDialogService
        ProjectRepository openmebius.infrastructure.project.FileProjectRepository
        OpenProjectUseCase openmebius.application.project.OpenProjectUseCase
        CreateProjectUseCase openmebius.application.project.CreateProjectUseCase
        ProjectSession openmebius.domain.project.ProjectSession
        TemplateModelLoadService openmebius.application.model.TemplateModelLoadService
        BatchLoadService openmebius.application.batch.BatchLoadService
        BatchRunController openmebius.application.batch.BatchRunController
        ResultLoadService openmebius.application.result.ResultLoadService
        ResultExportService openmebius.application.result.ResultExportService
        ReportGenerationService openmebius.application.report.ReportGenerationService
        ExperimentImportController openmebius.application.experiment.ExperimentImportController
        ExperimentCalculationController openmebius.application.experiment.ExperimentCalculationController
        ExperimentEditController openmebius.application.experiment.ExperimentEditController

        LegacyProjectLoader openmebius.infrastructure.legacy.LegacyProjectLoader
        LegacyProjectInitializer openmebius.infrastructure.legacy.LegacyProjectInitializer
        LegacyListeners event.listener = event.listener.empty(0, 1)

        SlackNotifier openmebius.infrastructure.notification.SlackWebhookNotifier

        PreferencesApp
        PreferencesListeners event.listener = event.listener.empty(0, 1)
        MSViewListeners event.listener = event.listener.empty(0, 1)

        MainInteractionSnapshot cell = {}

    end % properties (Access=private)

    methods (Access = public)

        %% Public check methods
        function isDark = isDarkTheme(app)
            bgColor = app.OpenMebius2UIFigure.Color;
            % The sum of RGB values is less than a certain threshold to determine if it is a dark theme
            brightness = sum(bgColor);
            isDark = brightness < 1.5; % The threshold can be adjusted empirically (1.5 to 1.8 is a guideline)
        end % function isDarkTheme

        function LogText(app, text)

            arguments
                app OpenMebius2
                text string
            end

            app.appendLogText(text);

        end % function LogText

        function LogTextDate(app, text, level)

            arguments
                app OpenMebius2
                text string
                level string
            end

            notification = ...
                openmebius.presentation.notification.Notification( ...
                text, ...
                level);

            app.showNotification(notification);

        end % function LogTextDate

        function saveHistory(app)

            projectHistory = app.ProjectDirectoryDropDown.Items;
            tempModelHistory = app.TemplateModelDirectoryDropDown.Items;

            system = System();
            directoryLog = system.getCacheDirectory();

            if isempty(directoryLog)
                return;
            end

            fileLog = fullfile(directoryLog, 'history.mat');

            if ~isfolder(directoryLog)
                mkdir(directoryLog);
            end

            save(fileLog, 'projectHistory', 'tempModelHistory');

        end % function saveHistory

        function clearHistory(app)

            app.ProjectDirectoryDropDown.Items = {};
            app.TemplateModelDirectoryDropDown.Items = {};
            app.saveHistory();

        end % function clearHistory

        %% Public update functions
        function updateStatus(app, section, status)
            % UPDATESTATUS Update the status table with the current status

            arguments
                app
                section string {mustBeMember(section, ["model", "experiment", "batch", "result"])}
                status string {mustBeMember(status, ["init", "running", "finished", "error"])}
            end

            [app.calcStatus, rows] = ...
                openmebius.presentation.status.StatusPresenter.update( ...
                app.calcStatus, ...
                section, ...
                status);

            initStatusTable(app, update = true, rows = rows);

        end % method updateStatus

        function updateModel(app)

            if isempty(app.model) || ~isvalid(app.model)
                return
            elseif isempty(app.exp) || ~isvalid(app.exp)
                msg = "Experiment data is not loaded. Cannot update model.";
                LogTextDate(app, msg, "Error");
                return
            elseif isempty(app.batch) || ~isvalid(app.batch)
                msg = "Batch data is not loaded. Cannot update model.";
                LogTextDate(app, msg, "Error");
                return
            end

            app.exp.updateModel(app.model);
            app.batch.updateExperimentalData(app.exp);

        end % method updateModel

        function updateBatchTable(app)

            loadBatchTable(app, reload = true);
            app.refreshPresentation();

        end % function updateBatchTable

        %% Public test methods
        function testResultSubTableCellSelection(app)
            % TESTRESULTSUBTABLECELLSELECTION Test the cell selection in the ResultSubTable
            %
            % Trigger the event.

            selection = app.ResultSubTable.Selection;

            ResultSubTableCellSelection(app, selection);

        end % function testResultSubTableCellSelection

        function testResultMainTableCellSelection(app)
            % TESTRESULTMAINTABLECELLSELECTION Test the cell selection in the ResultMainTable
            %
            % Trigger the event.

            selection = app.ResultMainTable.Selection;

            ResultMainTableCellSelection(app, selection);

        end % function testResultMainTableCellSelection

    end % methods (Access = public)

    methods (Access = protected)

        %% Protected wrapper functions
        function [folder, isOK] = uiGetDirWrap(app, options)

            arguments
                app
                options.Parent = []
                options.Title (1, 1) string = "Select folder"
                options.StartPath (1, 1) string = string(pwd)
            end

            app.ensureDialogService();

            [folder, isOK] = app.DialogService.selectFolder( ...
                Title = options.Title, ...
                StartPath = options.StartPath);

        end % function uiGetDirWrap

        function [files, isOK] = uiGetFileWrap(app, options)

            arguments
                app
                options.Parent = []
                options.Filter = "*.*"
                options.Title (1, 1) string = "Select file"
                options.StartPath (1, 1) string = string(pwd)
                options.MultiSelect (1, 1) string {mustBeMember(options.MultiSelect, ["off", "on"])} = "off"
                options.Save (1, 1) logical = false
                options.DefaultName (1, 1) string = ""
            end

            app.ensureDialogService();

            [files, isOK] = app.DialogService.selectFile( ...
                Filter = options.Filter, ...
                Title = options.Title, ...
                StartPath = options.StartPath, ...
                MultiSelect = options.MultiSelect, ...
                Save = options.Save, ...
                DefaultName = options.DefaultName);

        end % function uiGetFileWrap

        function [answer, isOK] = uiInputDlgWrap(app, options)

            arguments
                app
                options.Prompt (1, :) string = "Input"
                options.Title (1, 1) string = "Input dialog"
                options.Default (1, :) string = ""
                options.Dims (1, 2) double = [1 50]
            end

            app.ensureDialogService();

            [answer, isOK] = app.DialogService.inputText( ...
                Prompt = options.Prompt, ...
                Title = options.Title, ...
                Default = options.Default, ...
                Dims = options.Dims);

        end % function uiInputDlgWrap

        function [answer, isOK] = uiConfirmWrap(app, message, title, options)

            arguments
                app
                message (1, 1) string
                title (1, 1) string = "Confirm"
                options.Options (1, :) string = ["OK", "Cancel"]
                options.DefaultOption (1, 1) string = "OK"
                options.CancelOption (1, 1) string = "Cancel"
                options.Icon (1, 1) string = "question"
            end

            app.ensureDialogService();

            [answer, isOK] = app.DialogService.confirm( ...
                message, ...
                title, ...
                Options = options.Options, ...
                DefaultOption = options.DefaultOption, ...
                CancelOption = options.CancelOption, ...
                Icon = options.Icon);

        end % function uiConfirmWrap

        function uiAlertWrap(app, message, options)

            arguments
                app
                message (1, 1) string
                options.Title (1, 1) string = "Message"
                options.Icon (1, 1) string = "info"
                options.Interpreter (1, 1) string = "none"
            end

            app.ensureDialogService();

            app.DialogService.alert( ...
                message, ...
                Title = options.Title, ...
                Icon = options.Icon, ...
                Interpreter = options.Interpreter);

        end % function uiAlertWrap

    end % methods (Access = protected)

    methods (Access = private)

        %% Private presentation adapter functions
        function renderMainViewModel(app, viewModel)
            % RENDERMAINVIEWMODEL Render the main view model
            % renderMainViewModel(app, viewModel)
            %
            %  Input:
            %   viewModel: An object of class MainViewModel

            if isempty(viewModel)
                return
            end

            if isprop(viewModel, "UiState")
                app.renderUiState(viewModel.UiState);
                return
            end

            % For transitional versions where viewModel may be a struct.
            if isstruct(viewModel) && isfield(viewModel, "UiState")
                app.renderUiState(viewModel.UiState);
                return
            end

            error( ...
                "OpenMebius2:Presentation:InvalidViewModel", ...
            "MainViewModel must contain UiState.");

        end % method renderMainViewModel

        function renderLegacyProjectArtifacts(app)

            % -------------------------------------------------------------
            % Model UI
            % -------------------------------------------------------------
            app.updateStatus("model", "running");

            app.notifyInfo("Constructing EMU network...");

            loadEMUModel(app)

            loadPathway(app)

            app.notifyInfo("EMU network was successfully constructed.");
            app.updateStatus("model", "finished");

            % -------------------------------------------------------------
            % Experiment UI
            % -------------------------------------------------------------
            app.updateStatus("experiment", "running");

            loadExpData(app)

            app.updateStatus("experiment", "finished");

            % -------------------------------------------------------------
            % Batch UI
            % -------------------------------------------------------------
            app.updateStatus("batch", "running");

            loadBatchTable(app, reload = true)

            app.updateStatus("batch", "finished");
            app.notifyInfo("Batch table loaded successfully.");

            % -------------------------------------------------------------
            % Result UI
            % -------------------------------------------------------------
            app.updateStatus("result", "running");

            loadResult(app, reload = true)

            if isempty(app.ResultSubTable.Data)
                app.updateStatus("result", "init");
                app.notifyInfo("No result files found in the results directory.");
            else
                app.updateStatus("result", "finished");
                app.notifyInfo("Result files loaded successfully.");
            end

        end % method renderLegacyProjectArtifacts

        function renderCreatedProjectArtifacts(app)

            app.updateStatus("model", "running");

            app.notifyInfo("Constructing EMU network...");

            pause(0.5)

            loadEMUModel(app)

            loadPathway(app)

            app.updateStatus("model", "finished");
            app.notifyInfo("New project created and model loaded successfully.");

            app.updateStatus("experiment", "init");
            app.updateStatus("batch", "init");
            app.updateStatus("result", "init");

        end % method renderCreatedProjectArtifacts

        function renderBatchTable(app, viewModel)

            if isempty(viewModel)
                return
            end

            removeStyle(app.RunTable);

            app.RunTable.Data = viewModel.Data;

            if isempty(viewModel.Data)
                app.RunTable.ColumnName = [];
                app.RunTable.RowName = [];
                app.RunTable.ColumnEditable = false;
                return
            end

            app.RunTable.ColumnName = ...
                viewModel.Data.Properties.VariableNames;

            app.RunTable.RowName = ...
                viewModel.Data.Properties.RowNames;

            app.RunTable.ColumnEditable = ...
                viewModel.ColumnEditable;

            app.applyBatchStyleRules(viewModel.StyleRules);

        end % method renderBatchTable

        function renderBatchProgress(app, viewModel)

            if isempty(viewModel)
                return
            end

            app.ensureProgressBar();

            app.ProgressBar.setProgress( ...
                viewModel.Rate, ...
                viewModel.Message);

            drawnow limitrate

        end % method renderBatchProgress

        function renderBatchRunViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            if viewModel.SectionStatus ~= ""
                app.updateStatus("batch", viewModel.SectionStatus);
            end

            if ~isempty(viewModel.Notification)
                app.showNotification(viewModel.Notification);
            end

            if viewModel.CompletionStatus ~= ""
                app.notifySlackBatchCompleted( ...
                    viewModel.CompletionStatus, ...
                    ErrorMessage = viewModel.ErrorMessage, ...
                    DeltaTime = viewModel.ElapsedTime);
            end

        end % renderBatchRunViewModel

        function renderExperimentCalculationViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            app.updateStatus("experiment", viewModel.SectionStatus);

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.showNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

        end % renderExperimentCalculationViewModel

        function renderExperimentImportViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            if ~isempty(viewModel.Result)
                app.renderExperimentImportResult( ...
                    viewModel.Result, ...
                    LogMessages = false);
            end

            if viewModel.SectionStatus ~= ""
                app.updateStatus( ...
                    "experiment", ...
                    viewModel.SectionStatus);
            end

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.showNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

        end % renderExperimentImportViewModel

        function renderExperimentEditViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            if ~isempty(viewModel.UpdatedTable)
                app.LabelTable.Data = viewModel.UpdatedTable;
            end

            if viewModel.SectionStatus ~= ""
                app.updateStatus( ...
                    "experiment", ...
                    viewModel.SectionStatus);
            end

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.showNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

        end % renderExperimentEditViewModel

        function renderResultMainTable(app, viewModel)

            if isempty(viewModel)
                return
            end

            removeStyle(app.ResultMainTable);

            app.ResultMainTable.Data = viewModel.Data;

            try
                app.ResultMainTable.UserData = struct( ...
                    "RawData", viewModel.RawData);
            catch
                % If UserData is unavailable for some MATLAB release,
                % updateResultPlot will fall back to ResultMainTable.Data.
            end

            if isempty(viewModel.Data)
                app.ResultMainTable.ColumnName = [];
                app.ResultMainTable.RowName = [];
                app.ResultMainTable.ColumnEditable = false;
                return
            end

            app.ResultMainTable.ColumnName = ...
                viewModel.Data.Properties.VariableNames;

            app.ResultMainTable.RowName = ...
                viewModel.Data.Properties.RowNames;

            app.ResultMainTable.ColumnEditable = ...
                viewModel.ColumnEditable;

            app.applyResultStyleRules( ...
                app.ResultMainTable, ...
                viewModel.StyleRules);

        end % method renderResultMainTable

        function renderResultSubTable(app, viewModel)

            if isempty(viewModel)
                return
            end

            removeStyle(app.ResultSubTable);

            app.ResultSubTable.Data = viewModel.Data;

            if isempty(viewModel.Data)
                app.ResultSubTable.ColumnName = [];
                app.ResultSubTable.RowName = [];
                app.ResultSubTable.ColumnEditable = false;
                return
            end

            app.ResultSubTable.ColumnName = ...
                viewModel.Data.Properties.VariableNames;

            app.ResultSubTable.RowName = ...
                viewModel.Data.Properties.RowNames;

            app.ResultSubTable.ColumnEditable = ...
                viewModel.ColumnEditable;

            app.applyResultStyleRules( ...
                app.ResultSubTable, ...
                viewModel.StyleRules);

        end % method renderResultSubTable

        function renderResultPlot(app, viewModel)

            if isempty(viewModel)
                return
            end

            if ~isempty(viewModel.Notification)
                app.showNotification(viewModel.Notification);
            end

            switch viewModel.Kind

                case openmebius.presentation.result.ResultPlotKind.None
                    app.clearResultPlots();

                case openmebius.presentation.result.ResultPlotKind.OverviewFlux
                    app.renderOverviewResultPlot(viewModel);

                otherwise
                    app.clearResultPlots();
            end

        end % method renderResultPlot

        function renderOverviewResultPlot(app, viewModel)

            mainPlot = viewModel.MainPlot;
            subPlot = viewModel.SubPlot;

            app.MainUIAxes.Visible = 'on';
            app.SubUIAxes.Visible = 'on';

            if isfield(mainPlot, "Kind") && mainPlot.Kind == "legacy-flux-pathway"

                drawFluxLabel( ...
                    mainPlot.Model, ...
                    app.MainUIAxes, ...
                    mainPlot.FluxLabels, ...
                    highlight = mainPlot.HighlightMask, ...
                    darkmode = mainPlot.IsDarkTheme);

            else
                cla(app.MainUIAxes);
            end

            if isfield(subPlot, "Kind") && subPlot.Kind == "legacy-ci-reaction"

                if isempty(subPlot.Data) || ~isfield(subPlot.Data, "CI")
                    cla(app.SubUIAxes);
                    return
                end

                cla(app.SubUIAxes);

                drawCIReaction( ...
                    subPlot.Result, ...
                    app.SubUIAxes, ...
                    subPlot.Data);

            else
                cla(app.SubUIAxes);
            end

        end % method renderOverviewResultPlot

        function clearResultPlots(app)

            cla(app.MainUIAxes);
            cla(app.SubUIAxes);

            app.MainUIAxes.Visible = 'on';
            app.SubUIAxes.Visible = 'on';

        end % method clearResultPlots

        function renderUiState(app, ui)
            % RENDERUISTATE Render the UI state
            % renderUiState(app, ui)
            %
            %  Input:
            %   ui: An object of class UiState

            if isempty(ui)
                return
            end

            if isfield(ui, "MainInteractionEnabled")

                app.applyMainInteractionEnabled(ui.MainInteractionEnabled);

                if ~ui.MainInteractionEnabled
                    return
                end

            end

            % Project panel
            % ---------------------------------------------------------------------
            if isfield(ui, "ProjectPanelEnabled")
                app.ProjectPanel.Enable = app.onOff(ui.ProjectPanelEnabled);
            end

            if isfield(ui, "ProjectBrowseEnabled")
                app.ProjectBrowseButton.Enable = app.onOff(ui.ProjectBrowseEnabled);
            end

            if isfield(ui, "ProjectDirectoryEnabled")
                app.ProjectDirectoryDropDown.Enable = ...
                    app.onOff(ui.ProjectDirectoryEnabled);
            end

            if isfield(ui, "ProjectLoadEnabled")
                app.ProjectLoadButton.Enable = app.onOff(ui.ProjectLoadEnabled);
            end

            if isfield(ui, "ProjectMetadataEditable")
                value = app.onOff(ui.ProjectMetadataEditable);
                app.ProjectNameEditField.Enable = value;
                app.ProjectAuthorEditField.Enable = value;
                app.OrganismEditField.Enable = value;
            end

            if isfield(ui, "ProjectSaveEnabled")
                app.ProjectSaveButton.Enable = app.onOff(ui.ProjectSaveEnabled);
            end

            if isfield(ui, "TemplateModelBrowseEnabled")
                app.TemplateModelBrowseButton.Enable = ...
                    app.onOff(ui.TemplateModelBrowseEnabled);
            end

            if isfield(ui, "TemplateModelDirectoryEnabled")
                app.TemplateModelDirectoryDropDown.Enable = ...
                    app.onOff(ui.TemplateModelDirectoryEnabled);
            end

            if isfield(ui, "TemplateModelLoadEnabled")
                app.TemplateModelLoadButton.Enable = ...
                    app.onOff(ui.TemplateModelLoadEnabled);
            end

            if isfield(ui, "TemplateModelSaveEnabled")
                app.TemplateModelSaveButton.Enable = ...
                    app.onOff(ui.TemplateModelSaveEnabled);
            end

            if isfield(ui, "ProjectCreateEnabled")
                app.ProjectCreateButton.Enable = ...
                    app.onOff(ui.ProjectCreateEnabled);
            end

            % Stoichiometry tab
            if isfield(ui, "ModelEnabled")
                value = app.onOff(ui.ModelEnabled);
                app.ModelTable.Enable = value;
                app.ModelReloadButton.Enable = value;
            end

            if isfield(ui, "ModelEditEnabled")
                app.ModelEditButton.Enable = app.onOff(ui.ModelEditEnabled);
            end

            if isfield(ui, "ModelSaveEnabled")
                app.ModelSaveButton.Enable = app.onOff(ui.ModelSaveEnabled);
            end

            if isfield(ui, "ModelTableEditable")
                app.applyTableEditable(app.ModelTable, ui.ModelTableEditable);
            end

            % MS tab
            if isfield(ui, "MsEnabled")
                value = app.onOff(ui.MsEnabled);
                app.MSTable.Enable = value;
                app.AtomTable.Enable = value;
                app.MSReloadButton.Enable = value;
            end

            if isfield(ui, "MsEditEnabled")
                app.MSEditButton.Enable = app.onOff(ui.MsEditEnabled);
            end

            if isfield(ui, "MsSaveEnabled")
                app.MSSaveButton.Enable = app.onOff(ui.MsSaveEnabled);
            end

            if isfield(ui, "MsTableEditable")
                app.applyTableEditable(app.MSTable, ui.MsTableEditable);
            end

            if isfield(ui, "AtomTableEditable")
                app.applyTableEditable(app.AtomTable, ui.AtomTableEditable);
            end

            % Experiment tab
            if isfield(ui, "ExperimentEnabled")
                value = app.onOff(ui.ExperimentEnabled);
                app.ExpTable.Enable = value;
                app.BiomassTable.Enable = value;
                app.ExpCalculationButton.Enable = value;
                app.ExpImportButton.Enable = value;
                app.ExpReloadButton.Enable = value;
                app.ExpSaveButton.Enable = value;

                app.applyContextMenu( ...
                    app.ExpTable, ...
                    app.ExperimentContextMenu, ...
                    ui.ExperimentEnabled);
            end

            if isfield(ui, "ExperimentTableEditable")
                app.applyTableEditable(app.ExpTable, ui.ExperimentTableEditable);
            end

            if isfield(ui, "BiomassTableEditable")
                app.applyTableEditable(app.BiomassTable, ui.BiomassTableEditable);
            end

            % Tracer tab
            if isfield(ui, "TracerEnabled")
                value = app.onOff(ui.TracerEnabled);
                app.UptakeTable.Enable = value;
                app.LabelTable.Enable = value;
                app.TracerConfigButton.Enable = value;
                app.TracerReloadButton.Enable = value;
                app.TracerSaveButton.Enable = value;

                app.applyContextMenu( ...
                    app.LabelTable, ...
                    app.ContextMenu3, ...
                    ui.TracerEnabled);
            end

            if isfield(ui, "TracerTableEditable")
                app.applyTableEditable(app.LabelTable, ui.TracerTableEditable);
            end

            if isfield(ui, "UptakeTableEditable")
                app.applyTableEditable(app.UptakeTable, ui.UptakeTableEditable);
            end

            % Run tab
            if isfield(ui, "RunConfigurationEnabled")
                value = app.onOff(ui.RunConfigurationEnabled);
                app.RunAutoButton.Enable = value;
                app.RunConfigButton.Enable = value;
                app.RunReloadButton.Enable = value;
                app.RunSaveButton.Enable = value;
            end

            if isfield(ui, "RunTableEnabled")
                app.RunTable.Enable = app.onOff(ui.RunTableEnabled);
            end

            if isfield(ui, "RunContextMenuEnabled")
                app.applyContextMenu( ...
                    app.RunTable, ...
                    app.ContextMenuRun, ...
                    ui.RunContextMenuEnabled);

            elseif isfield(ui, "RunTableEnabled")
                app.applyContextMenu( ...
                    app.RunTable, ...
                    app.ContextMenuRun, ...
                    ui.RunTableEnabled);
            end

            if isfield(ui, "RunTableEditable")

                if ui.RunTableEditable
                    app.restoreRunTableEditable();
                else
                    app.rememberRunTableEditable();
                    app.applyTableEditable(app.RunTable, false);
                end

            end

            if isfield(ui, "RunButtonEnabled")
                app.RunRunButton.Enable = app.onOff(ui.RunButtonEnabled);
            end

            if isfield(ui, "RunButtonText")
                app.RunRunButton.Text = char(ui.RunButtonText);
            end

            % Result tab
            if isfield(ui, "ResultEnabled")
                value = app.onOff(ui.ResultEnabled);
                app.ResultDropDown.Enable = value;
                app.ResultMainTable.Enable = value;
                app.ResultSubTable.Enable = value;
                app.ResultReportButton.Enable = value;
                app.ResultReloadButton.Enable = value;
                app.ResultSaveButton.Enable = value;

                app.applyContextMenu( ...
                    app.ResultMainTable, ...
                    app.ContextMenu2, ...
                    ui.ResultEnabled);

                app.applyContextMenu( ...
                    app.ResultSubTable, ...
                    app.ContextMenuResultSelect, ...
                    ui.ResultEnabled);
            end

            if isfield(ui, "ResultMainTableEditable")
                app.applyTableEditable(app.ResultMainTable, ...
                    ui.ResultMainTableEditable);
            end

            if isfield(ui, "ResultSubTableEditable")
                app.applyTableEditable(app.ResultSubTable, ...
                    ui.ResultSubTableEditable);
            end

            % Menu
            if isfield(ui, "MenuEnabled")
                value = app.onOff(ui.MenuEnabled);
                app.FilesMenu.Enable = value;
                app.ModelMenu.Enable = value;
                app.BatchMenu.Enable = value;
                app.ViewMenu.Enable = value;
            end

            % Pathway context menu
            if isfield(ui, "PathwayContextMenuEnabled")
                app.applyContextMenu( ...
                    app.MainUIAxes, ...
                    app.ContextMenu, ...
                    ui.PathwayContextMenuEnabled);
            end

        end % method renderUiState

        function value = onOff(~, enabled)

            if enabled
                value = 'on';
            else
                value = 'off';
            end

        end

        function applyContextMenu(~, component, contextMenu, enabled)

            if enabled
                component.ContextMenu = contextMenu;
            else
                component.ContextMenu = [];
            end

        end

        function applyTableEditable(~, tableObject, editable)
            % APPLYTABLEEDITABLE
            % Applies a scalar or vector editable flag to a UITable.

            if isempty(tableObject)
                return
            end

            if isempty(tableObject.Data)
                tableObject.ColumnEditable = false;
                return
            end

            if isscalar(editable)
                n = 1;

                try

                    if istable(tableObject.Data)
                        n = width(tableObject.Data);
                    else
                        n = size(tableObject.Data, 2);
                    end

                catch
                    n = 1;
                end

                tableObject.ColumnEditable = repmat(logical(editable), 1, n);
                return
            end

            tableObject.ColumnEditable = logical(editable);
        end

        function initializePresentation(app)

            if isempty(app.Presenter)
                app.Presenter = ...
                    openmebius.presentation.main.MainPresenter();
            end

            app.refreshPresentation();

        end % method initializePresentation

        function refreshPresentation(app)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.refresh(context);
            app.renderMainViewModel(viewModel);

        end % method refreshPresentation

        function cleanup = beginPresentationOperation(app)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.beginOperation(context);
            app.renderMainViewModel(viewModel);

            cleanup = onCleanup(@() app.finishPresentationOperation());

        end % method beginPresentationOperation

        function finishPresentationOperation(app)

            if isempty(app.Presenter)
                return
            end

            context = app.capturePresentationContext();
            viewModel = app.Presenter.finishOperation(context);
            app.renderMainViewModel(viewModel);

        end % method finishPresentationOperation

        function beginPresentationEditCommit(app)

            context = app.capturePresentationContext();

            viewModel = app.Presenter.beginEditCommit(context);

            app.renderMainViewModel(viewModel);

        end

        function finishPresentationEditCommit(app, success)

            arguments
                app
                success (1, 1) logical
            end

            context = app.capturePresentationContext();

            viewModel = app.Presenter.finishEditCommit(context, success);

            app.renderMainViewModel(viewModel);

        end

        function resetPresentation(app)

            if isempty(app.Presenter)
                app.Presenter = ...
                    openmebius.presentation.main.MainPresenter();
            end

            context = app.capturePresentationContext();

            viewModel = app.Presenter.reset(context);

            app.renderMainViewModel(viewModel);

        end

        function beginPresentationRun(app)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.beginRun(context);
            app.renderMainViewModel(viewModel);
            drawnow;

        end % method beginPresentationRun

        function finishPresentationRun(app)

            if isempty(app.Presenter)
                return
            end

            context = app.capturePresentationContext();
            viewModel = app.Presenter.finishRun(context);
            app.renderMainViewModel(viewModel);

        end % method finishPresentationRun

        function finishPresentationRunSafely(app)

            try

                if isempty(app.Presenter)
                    return
                end

                context = app.capturePresentationContext();
                viewModel = app.Presenter.finishRun(context);
                app.renderMainViewModel(viewModel);
                drawnow;

            catch ME

                try
                    app.LogTextDate( ...
                        "Failed to restore run UI state: " + string(ME.message), ...
                    "Error");
                catch
                end

            end

        end % method finishPresentationRunSafely

        function requestPresentationCancelRun(app)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.requestCancelRun(context);
            app.renderMainViewModel(viewModel);
            drawnow;

        end % method requestPresentationCancelRun

        function beginPresentationEdit(app, target)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.beginEdit(target, context);
            app.renderMainViewModel(viewModel);

        end % method beginPresentationEdit

        function finishPresentationEdit(app)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.finishEdit(context);
            app.renderMainViewModel(viewModel);

        end % method finishPresentationEdit

        function rememberRunTableEditable(app)

            if isempty(app.RunTable)
                return
            end

            if isempty(app.RunTable.Data)
                return
            end

            try
                current = app.RunTable.ColumnEditable;

                if isempty(current)
                    return
                end

                app.RunTableEditable = logical(current);

            catch
                % Do not fail rendering due to transient UITable state.
            end

        end % method rememberRunTableEditable

        function restoreRunTableEditable(app)

            try

                if isempty(app.RunTableEditable)
                    return
                end

                app.RunTable.ColumnEditable = app.RunTableEditable;

            catch
                % If the table shape has changed, keep current ColumnEditable.
            end

        end % method restoreRunTableEditable

        function context = capturePresentationContext(app)

            context = struct();

            % ---------------------------------------------------------------------
            % Project paths
            % ---------------------------------------------------------------------

            context.ProjectDirectory = ...
                app.safeStringScalar(app.ProjectDirectoryDropDown.Value);

            context.TemplateModelDirectory = ...
                app.safeStringScalar(app.TemplateModelDirectoryDropDown.Value);

            context.DirectoryModel = app.safeStringScalar(app.directoryModel);
            context.DirectoryExp = app.safeStringScalar(app.directoryExp);
            context.DirectoryResult = app.safeStringScalar(app.directoryResult);

            context.ProjectDirectoryExists = ...
                context.ProjectDirectory ~= "" && isfolder(context.ProjectDirectory);

            context.TemplateModelDirectoryExists = ...
                context.TemplateModelDirectory ~= "" && ...
                isfolder(context.TemplateModelDirectory);

            context.DirectoryModelExists = ...
                context.DirectoryModel ~= "" && isfolder(context.DirectoryModel);

            context.DirectoryExpExists = ...
                context.DirectoryExp ~= "" && isfolder(context.DirectoryExp);

            context.DirectoryResultExists = ...
                context.DirectoryResult ~= "" && isfolder(context.DirectoryResult);

            % ---------------------------------------------------------------------
            % Project metadata fields
            % ---------------------------------------------------------------------
            context.ProjectName = ...
                app.safeStringScalar(app.ProjectNameEditField.Value);

            context.ProjectAuthor = ...
                app.safeStringScalar(app.ProjectAuthorEditField.Value);

            context.Organism = ...
                app.safeStringScalar(app.OrganismEditField.Value);

            context.HasProjectMetadata = ...
                context.ProjectName ~= "" || ...
                context.ProjectAuthor ~= "" || ...
                context.Organism ~= "";

            % ---------------------------------------------------------------------
            % Legacy domain objects
            % ---------------------------------------------------------------------
            context.HasModelObject = app.isLoadedObject(app.model);
            context.HasExperimentObject = app.isLoadedObject(app.exp);
            context.HasBatchObject = app.isLoadedObject(app.batch);
            context.HasResultObject = app.isLoadedObject(app.result);

            context.HasModelError = app.objectHasError(app.model);
            context.HasExperimentError = app.objectHasError(app.exp);
            context.HasBatchError = app.objectHasError(app.batch);
            context.HasResultError = app.objectHasError(app.result);

            context.HasModel = ...
                context.HasModelObject && ~context.HasModelError;

            context.HasExperiments = ...
                context.HasExperimentObject && ~context.HasExperimentError;

            context.HasBatches = ...
                context.HasBatchObject && ~context.HasBatchError;

            % In the current GUI, the Result tab can be useful as soon as IOResult
            % exists, even if no result rows are displayed yet.
            context.HasResults = ...
                context.HasResultObject && ~context.HasResultError;

            % A project is considered fully loaded only when the project directories
            % and the four major legacy objects are available.
            context.HasProject = ...
                context.ProjectDirectoryExists && ...
                context.DirectoryModelExists && ...
                context.DirectoryExpExists && ...
                context.DirectoryResultExists && ...
                context.HasModel && ...
                context.HasExperiments && ...
                context.HasBatches && ...
                context.HasResults;

            % Template mode is used after loading a template model but before
            % creating a full project.
            context.IsTemplateMode = ...
                ~context.HasProject && ...
                context.TemplateModelDirectoryExists && ...
                context.HasModel;

            context.CanCreateProjectFromTemplate = context.IsTemplateMode;

            % ---------------------------------------------------------------------
            % Status table state
            % ---------------------------------------------------------------------
            calcStatus = strings(4, 1);
            calcStatus(:) = "init";

            try
                n = min(4, numel(app.calcStatus));
                calcStatus(1:n) = string(app.calcStatus(1:n));
            catch
                % Keep default "init" values.
            end

            context.Status = struct();
            context.Status.Model = calcStatus(1);
            context.Status.Experiment = calcStatus(2);
            context.Status.Batch = calcStatus(3);
            context.Status.Result = calcStatus(4);

            % ---------------------------------------------------------------------
            % Table contents
            % ---------------------------------------------------------------------
            context.ModelTableRowCount = app.tableRowCount(app.ModelTable.Data);
            context.MSTableRowCount = app.tableRowCount(app.MSTable.Data);
            context.AtomTableRowCount = app.tableRowCount(app.AtomTable.Data);
            context.ExpTableRowCount = app.tableRowCount(app.ExpTable.Data);
            context.BiomassTableRowCount = app.tableRowCount(app.BiomassTable.Data);
            context.UptakeTableRowCount = app.tableRowCount(app.UptakeTable.Data);
            context.LabelTableRowCount = app.tableRowCount(app.LabelTable.Data);
            context.RunTableRowCount = app.tableRowCount(app.RunTable.Data);
            context.ResultSubTableRowCount = app.tableRowCount(app.ResultSubTable.Data);
            context.ResultMainTableRowCount = app.tableRowCount(app.ResultMainTable.Data);

            context.HasModelRows = context.ModelTableRowCount > 0;
            context.HasMSRows = context.MSTableRowCount > 0;
            context.HasExperimentRows = context.ExpTableRowCount > 0;
            context.HasTracerRows = context.LabelTableRowCount > 0 || ...
                context.UptakeTableRowCount > 0;
            context.HasBatchRows = context.RunTableRowCount > 0;
            context.HasResultRows = context.ResultSubTableRowCount > 0;

            % For the first Presenter / UiPolicy step, this should approximate the
            % current GUI behavior without changing domain logic.
            context.CanRun = ...
                context.HasProject && ...
                context.HasModel && ...
                context.HasExperiments && ...
                context.HasBatches && ...
                context.HasBatchRows && ...
                context.DirectoryResultExists;

            % ---------------------------------------------------------------------
            % Current edit states inferred from existing UI
            % ---------------------------------------------------------------------
            context.IsModelEditing = ...
                app.isEnabled(app.ModelSaveButton) && ...
                app.hasEditableColumn(app.ModelTable.ColumnEditable);

            context.IsMSEditing = ...
                app.isEnabled(app.MSSaveButton) && ...
                (app.hasEditableColumn(app.MSTable.ColumnEditable) || ...
                app.hasEditableColumn(app.AtomTable.ColumnEditable));

            % Current implementation has no explicit Experiment / Tracer edit mode.
            % Their tables are edited directly when the tab is unlocked.
            context.IsExperimentEditing = false;
            context.IsTracerEditing = false;

            context.IsAnyTableEditing = ...
                context.IsModelEditing || ...
                context.IsMSEditing || ...
                context.IsExperimentEditing || ...
                context.IsTracerEditing;

            % ---------------------------------------------------------------------
            % Running / child-window states
            % ---------------------------------------------------------------------
            context.IsBatchRunning = ...
                app.safeStringScalar(app.RunRunButton.Text) == "Cancel";

            context.IsProjectPanelLocked = ...
                ~app.isEnabled(app.ProjectPanel);

            context.HasOpenChildApp = any([
                                           app.isLoadedObject(app.LabelConfigApp)
                                           app.isLoadedObject(app.TracerConfigApp)
                                           app.isLoadedObject(app.RunConfigApp)
                                           app.isLoadedObject(app.MSViewApp)
                                           app.isLoadedObject(app.ComparisonViewApp)
                                           app.isLoadedObject(app.RunAddBatchApp)
                                           app.isLoadedObject(app.ViewSuggestionApp)
                                           app.isLoadedObject(app.LogApp)
                                           ]);

            context.HasProgressBar = app.isLoadedObject(app.ProgressBar);

            % ---------------------------------------------------------------------
            % Current selections
            % ---------------------------------------------------------------------
            context.SelectedRunRows = app.selectedRows(app.RunTable);
            context.SelectedResultRows = app.selectedRows(app.ResultSubTable);
            context.SelectedResultDetailRows = app.selectedRows(app.ResultMainTable);
            context.SelectedModelRows = app.selectedRows(app.ModelTable);
            context.SelectedExperimentRows = app.selectedRows(app.ExpTable);
            context.SelectedTracerRows = app.selectedRows(app.LabelTable);

            context.HasSelectedRunRows = ~isempty(context.SelectedRunRows);
            context.HasSelectedResultRows = ~isempty(context.SelectedResultRows);
            context.HasSelectedModelRows = ~isempty(context.SelectedModelRows);
            context.HasSelectedExperimentRows = ~isempty(context.SelectedExperimentRows);
            context.HasSelectedTracerRows = ~isempty(context.SelectedTracerRows);

            % ---------------------------------------------------------------------
            % Current view mode
            % ---------------------------------------------------------------------
            context.CurrentTab = "";

            try

                if ~isempty(app.TabGroup.SelectedTab)
                    context.CurrentTab = ...
                        app.safeStringScalar(app.TabGroup.SelectedTab.Title);
                end

            catch
                context.CurrentTab = "";
            end

            context.ResultMode = ...
                app.safeStringScalar(app.ResultDropDown.Value);

            if context.ResultMode == ""
                context.ResultMode = "Overview";
            end

            context.TypeSimulation = ...
                app.safeStringScalar(app.typeSimulation);

            if context.TypeSimulation == ""
                context.TypeSimulation = "Flux";
            end

            % ---------------------------------------------------------------------
            % Current enabled state, useful during migration only.
            % Do not let MainUiPolicy depend on all of these permanently.
            % ---------------------------------------------------------------------
            context.LegacyUi = struct();

            context.LegacyUi.ProjectLoadEnabled = ...
                app.isEnabled(app.ProjectLoadButton);

            context.LegacyUi.TemplateModelLoadEnabled = ...
                app.isEnabled(app.TemplateModelLoadButton);

            context.LegacyUi.ProjectCreateEnabled = ...
                app.isEnabled(app.ProjectCreateButton);

            context.LegacyUi.TemplateModelSaveEnabled = ...
                app.isEnabled(app.TemplateModelSaveButton);

            context.LegacyUi.ModelEditEnabled = ...
                app.isEnabled(app.ModelEditButton);

            context.LegacyUi.ModelSaveEnabled = ...
                app.isEnabled(app.ModelSaveButton);

            context.LegacyUi.MSEditEnabled = ...
                app.isEnabled(app.MSEditButton);

            context.LegacyUi.MSSaveEnabled = ...
                app.isEnabled(app.MSSaveButton);

            context.LegacyUi.RunButtonEnabled = ...
                app.isEnabled(app.RunRunButton);

            context.LegacyUi.RunButtonText = ...
                app.safeStringScalar(app.RunRunButton.Text);

            context.LegacyUi.ResultReloadEnabled = ...
                app.isEnabled(app.ResultReloadButton);

        end % method capturePresentationContext

        function [status, msg] = extractGeneralMessagePayload(~, event)

            payload = event;

            % Unwrap event.EventData / BatchProgressEventData / nested struct.
            for i = 1:5

                if isstruct(payload) && ...
                        isfield(payload, 'status') && ...
                        isfield(payload, 'msg')
                    break
                end

                if isobject(payload)

                    try

                        if isprop(payload, 'data')
                            payload = payload.data;
                            continue
                        end

                    catch
                    end

                end

                if isstruct(payload) && isfield(payload, 'data')
                    payload = payload.data;
                    continue
                end

                break
            end

            if ~isstruct(payload) || ...
                    ~isfield(payload, 'status') || ...
                    ~isfield(payload, 'msg')

                error( ...
                    "OpenMebius2:Notification:InvalidGeneralMessagePayload", ...
                "GeneralMsg event does not contain status/msg payload.");
            end

            status = lower(string(payload.status));
            msg = string(payload.msg);

            if isempty(msg)
                msg = "";
            else
                msg = msg(1);
            end

            if isempty(status)
                status = "info";
            else
                status = status(1);
            end

            switch status
                case {"info", "warning", "error", "success"}
                    % keep
                case {"warn"}
                    status = "warning";
                case {"err", "exception"}
                    status = "error";
                case {"ok", "finished", "complete", "completed"}
                    status = "success";
                otherwise
                    status = "info";
            end

        end % method extractGeneralMessagePayload

        function tf = isLoadedObject(app, value)
            % ISLOADEDOBJECT
            % True when the value exists and, if it is a handle object, is valid.
            % This method intentionally does not inspect UI components.

            tf = false;

            if isempty(value)
                return
            end

            try

                if isobject(value)

                    try

                        if any(~isvalid(value), "all")
                            return
                        end

                    catch
                        % Some value objects do not support isvalid.
                        % In that case, non-empty is treated as loaded.
                    end

                end

                if app.objectHasError(value)
                    return
                end

                tf = true;

            catch
                tf = false;
            end

        end % function isLoadedObject

        function tf = objectHasError(~, value)
            % OBJECTHASERROR
            % Checks common legacy isError property without throwing.

            tf = false;

            if isempty(value)
                return
            end

            try

                if isobject(value) && isprop(value, "isError")
                    raw = value.isError;

                    if isempty(raw)
                        tf = false;
                    elseif islogical(raw)
                        tf = any(raw(:));
                    elseif isnumeric(raw)
                        tf = any(logical(raw(:)));
                    elseif isstring(raw) || ischar(raw)
                        raw = string(raw);
                        tf = any(raw == "true" | raw == "1" | raw == "error");
                    else
                        tf = false;
                    end

                end

            catch
                % If error state cannot be inspected, do not mark it as error.
                tf = false;
            end

        end % function objectHasError

        function n = tableRowCount(~, data)
            % TABLEROWCOUNT
            % Returns row count for table, cell, numeric array, string array, etc.

            n = 0;

            if isempty(data)
                return
            end

            try

                if istable(data)
                    n = height(data);
                else
                    n = size(data, 1);
                end

            catch
                n = 0;
            end

        end % function tableRowCount

        function n = tableWidthCount(~, data)
            % TABLEWIDTHCOUNT
            % Returns column count for table-like data.

            n = 0;

            if isempty(data)
                return
            end

            try

                if istable(data)
                    n = width(data);
                else
                    n = size(data, 2);
                end

            catch
                n = 0;
            end

        end % function tableWidthCount

        function tf = isEnabled(~, componentOrValue)
            % ISENABLED
            % Accepts either a UI component with Enable property or the Enable value.

            tf = false;

            try

                if ischar(componentOrValue) || isstring(componentOrValue)
                    value = string(componentOrValue);
                else
                    value = string(componentOrValue.Enable);
                end

                if isempty(value)
                    return
                end

                value = lower(value(1));

                tf = value == "on" || value == "true" || value == "1";

            catch
                tf = false;
            end

        end % function isEnabled

        function tf = hasEditableColumn(~, columnEditable)
            % HASEDITABLECOLUMN
            % True if any column is editable.

            tf = false;

            if isempty(columnEditable)
                return
            end

            try
                tf = any(logical(columnEditable(:)));
            catch
                tf = false;
            end

        end % function hasEditableColumn

        function rows = selectedRows(~, tableObject)
            % SELECTEDROWS
            % Extracts unique selected row indices from a UITable.

            rows = zeros(0, 1);

            try
                selection = tableObject.Selection;

                if isempty(selection)
                    return
                end

                if size(selection, 2) >= 1
                    rows = unique(selection(:, 1));
                    rows = rows(:);
                end

            catch
                rows = zeros(0, 1);
            end

        end % function selectedRows

        function value = safeStringScalar(~, raw)
            % SAFESTRINGSCALAR
            % Converts raw UI or property value to a non-missing scalar string.

            value = "";

            if isempty(raw)
                return
            end

            try
                value = string(raw);

                if isempty(value)
                    value = "";
                    return
                end

                value = value(1);

                if ismissing(value)
                    value = "";
                end

            catch
                value = "";
            end

        end % function safeStringScalar

        %% Private notification function
        function showNotification(app, notification)
            % SHOWNOTIFICATION
            % Central notification sink for OpenMebius2.mlapp.
            % showNotification(notification)

            if isempty(notification)
                return
            end

            if numel(notification) > 1

                for i = 1:numel(notification)
                    app.showNotification(notification(i));
                end

                return
            end

            if ~isa(notification, ...
                "openmebius.presentation.notification.Notification")

                notification = ...
                    openmebius.presentation.notification.Notification.info( ...
                    string(notification));

            end

            app.appendLogText( ...
                notification.Message, ...
                notification.Level, ...
                notification.Timestamp);

            if notification.ShowAlert

                uialert( ...
                    app.OpenMebius2UIFigure, ...
                    char(notification.Message), ...
                    char(notification.Title), ...
                    "Icon", char(notification.alertIcon()), ...
                    "Interpreter", "none");

            end

        end % method showNotification

        function openMSComparison(app)

            app.ComparisonViewApp = ComparisonView(app, "ms");

        end % method openMSComparison

        function appendLogText(app, text, level, timestamp)
            % APPENDLOGTEXT
            % Normalize and append log text to LogTextArea.

            arguments
                app
                text
                level string = "Info"
                timestamp (1, 1) datetime = datetime("now")
            end

            text = openmebius.infrastructure.logging.Logger ...
                .formatDatedLines( ...
                text, ...
                level, ...
                Timestamp = timestamp);

            before = app.LogTextArea.Value;
            app.LogTextArea.Value = [before; text(:)];

            scroll(app.LogTextArea, "bottom");

            drawnow limitrate

        end % method appendLogText

        function notifyInfo(app, message, options)

            arguments
                app
                message (1, 1) string
                options.Title (1, 1) string = ""
                options.Alert (1, 1) logical = false
            end

            app.showNotification( ...
                openmebius.presentation.notification.Notification.info( ...
                message, ...
                Title = options.Title, ...
                ShowAlert = options.Alert));

        end % method notifyInfo

        function notifyWarning(app, message, options)

            arguments
                app
                message (1, 1) string
                options.Title (1, 1) string = ""
                options.Alert (1, 1) logical = false
            end

            app.showNotification( ...
                openmebius.presentation.notification.Notification.warning( ...
                message, ...
                Title = options.Title, ...
                ShowAlert = options.Alert));

        end % method notifyWarning

        function notifyError(app, message, options)

            arguments
                app
                message (1, 1) string
                options.Title (1, 1) string = ""
                options.Alert (1, 1) logical = false
            end

            app.showNotification( ...
                openmebius.presentation.notification.Notification.error( ...
                message, ...
                Title = options.Title, ...
                ShowAlert = options.Alert));

        end % method notifyError

        function showModelValidationReport(app, validationReport)

            arguments
                app
                validationReport (1, 1) openmebius.domain.model ...
                    .ModelValidationReport
            end

            for i = 1:numel(validationReport.Warnings)
                app.notifyWarning(validationReport.Warnings(i));
            end

            if ~validationReport.IsValid
                app.notifyError(validationReport.ErrorMessage);
                return
            end

            for i = 1:numel(validationReport.Messages)
                app.notifyInfo(validationReport.Messages(i));
            end

        end % showModelValidationReport

        function notifyException(app, exception, options)

            arguments
                app
                exception
                options.Title (1, 1) string = "Error"
                options.Alert (1, 1) logical = false
            end

            app.showNotification( ...
                openmebius.presentation.notification.Notification.fromException( ...
                exception, ...
                Title = options.Title, ...
                ShowAlert = options.Alert));

        end % method notifyException

        function ensureDialogService(app)

            if isempty(app.DialogService)
                app.DialogService = ...
                    openmebius.presentation.dialog.AppDialogService( ...
                    app.OpenMebius2UIFigure);
                return
            end

            try
                app.DialogService.setParent(app.OpenMebius2UIFigure);
            catch
                app.DialogService = ...
                    openmebius.presentation.dialog.AppDialogService( ...
                    app.OpenMebius2UIFigure);
            end

        end % method ensureDialogService

        function ensureProjectServices(app)

            if isempty(app.ProjectRepository)
                app.ProjectRepository = ...
                    openmebius.infrastructure.project.FileProjectRepository();
            end

            if isempty(app.OpenProjectUseCase)
                app.OpenProjectUseCase = ...
                    openmebius.application.project.OpenProjectUseCase( ...
                    app.ProjectRepository);
            end

            if isempty(app.CreateProjectUseCase)
                app.CreateProjectUseCase = ...
                    openmebius.application.project.CreateProjectUseCase( ...
                    app.ProjectRepository);
            end

        end % method ensureProjectServices

        function ensureLegacyProjectInitializer(app)

            if isempty(app.LegacyProjectInitializer)
                app.LegacyProjectInitializer = ...
                    openmebius.infrastructure.legacy.LegacyProjectInitializer();
            end

        end % method ensureLegacyProjectInitializer

        function ensureTemplateModelLoadService(app)

            if isempty(app.TemplateModelLoadService)
                app.TemplateModelLoadService = ...
                    openmebius.application.model.TemplateModelLoadService();
            end

        end % method ensureTemplateModelLoadService

        function ensureExperimentImportController(app)

            if isempty(app.ExperimentImportController)
                app.ExperimentImportController = ...
                    openmebius.application.experiment ...
                    .ExperimentImportController();
            end

        end % method ensureExperimentImportController

        function ensureExperimentEditController(app)

            if isempty(app.ExperimentEditController)
                app.ExperimentEditController = ...
                    openmebius.application.experiment ...
                    .ExperimentEditController();
            end

        end % method ensureExperimentEditController

        function ensureBatchLoadService(app)

            if isempty(app.BatchLoadService)
                app.BatchLoadService = ...
                    openmebius.application.batch.BatchLoadService();
            end

        end % method ensureBatchLoadService

        function ensureResultLoadService(app)

            if isempty(app.ResultLoadService)
                app.ResultLoadService = ...
                    openmebius.application.result.ResultLoadService();
            end

        end % method ensureResultLoadService

        function ensureResultExportService(app)

            if isempty(app.ResultExportService)
                app.ResultExportService = ...
                    openmebius.application.result.ResultExportService();
            end

        end % method ensureResultExportService

        function ensureReportGenerationService(app)

            if isempty(app.ReportGenerationService)
                app.ReportGenerationService = ...
                    openmebius.application.report.ReportGenerationService();
            end

        end % method ensureReportGenerationService

        function attachLegacyListeners(app)

            app.LegacyListeners = event.listener.empty(0, 1);

            if ~isempty(app.batch) && isvalid(app.batch)

                app.LegacyListeners(end + 1, 1) = addlistener( ...
                    app.batch, ...
                    'ProgressUpdate', ...
                    @(src, event) statusBatch(app, event));

                app.LegacyListeners(end + 1, 1) = addlistener( ...
                    app.batch, ...
                    'GeneralMsg', ...
                    @(src, event) statusGeneralMsg(app, event));

                app.LegacyListeners(end + 1, 1) = addlistener( ...
                    app.batch, ...
                    'FluxResult', ...
                    @(src, event) updateResult(app, event));

            end

            if ~isempty(app.result) && isvalid(app.result)

                app.LegacyListeners(end + 1, 1) = addlistener( ...
                    app.result, ...
                    'GeneralMsg', ...
                    @(src, event) statusGeneralMsg(app, event));

            end

        end % method attachLegacyListeners

        function detachLegacyListeners(app)

            if isempty(app.LegacyListeners)
                return
            end

            for i = 1:numel(app.LegacyListeners)

                try

                    if isvalid(app.LegacyListeners(i))
                        delete(app.LegacyListeners(i));
                    end

                catch
                    % Ignore listener cleanup errors.
                end

            end

            app.LegacyListeners = event.listener.empty(0, 1);

        end % method detachLegacyListeners

        function attachPreferencesListeners(app, preferencesApp)

            app.detachPreferencesListeners();

            if isempty(preferencesApp) || ~isvalid(preferencesApp)
                return
            end

            app.PreferencesListeners(1, 1) = addlistener( ...
                preferencesApp, ...
                "PreferencesClosed", ...
                @(src, event) app.onPreferencesClosed(src, event));

        end % method attachPreferencesListeners

        function detachPreferencesListeners(app)

            if isempty(app.PreferencesListeners)
                return
            end

            for i = 1:numel(app.PreferencesListeners)

                try

                    if isvalid(app.PreferencesListeners(i))
                        delete(app.PreferencesListeners(i));
                    end

                catch
                end

            end

            app.PreferencesListeners = event.listener.empty(0, 1);

        end % method detachPreferencesListeners

        %% Apply Session
        function applyMainInteractionEnabled(app, enabled)

            arguments
                app
                enabled (1, 1) logical
            end

            if enabled
                app.restoreMainInteraction();
            else
                app.lockMainInteraction();
            end

        end % method applyMainInteractionEnabled

        function lockMainInteraction(app)

            if ~isempty(app.MainInteractionSnapshot)
                return
            end

            app.MainInteractionSnapshot = {};

            objects = findall(app.OpenMebius2UIFigure);

            for i = 1:numel(objects)

                obj = objects(i);

                try

                    if isprop(obj, "Enable")

                        app.MainInteractionSnapshot(end + 1, :) = { ...
                                                                       obj, ...
                                                                       obj.Enable}; %#ok<AGROW>

                        obj.Enable = 'off';

                    end

                catch
                    % Ignore components that cannot be locked.
                end

            end

            drawnow limitrate

        end % method lockMainInteraction

        function restoreMainInteraction(app)

            if isempty(app.MainInteractionSnapshot)
                return
            end

            snapshot = app.MainInteractionSnapshot;
            app.MainInteractionSnapshot = {};

            for i = 1:size(snapshot, 1)

                obj = snapshot{i, 1};
                value = snapshot{i, 2};

                try

                    if isvalid(obj) && isprop(obj, "Enable")
                        obj.Enable = value;
                    end

                catch
                    % Ignore stale UI handles.
                end

            end

            drawnow limitrate

        end % method restoreMainInteraction

        function applyProjectSession(app, session)

            arguments
                app
                session openmebius.domain.project.ProjectSession
            end

            app.ProjectSession = session;

            app.ProjectDirectoryDropDown.Value = ...
                session.Paths.RootDirectory;

            app.ProjectNameEditField.Value = ...
                session.Metadata.Name;

            app.ProjectAuthorEditField.Value = ...
                session.Metadata.Author;

            app.OrganismEditField.Value = ...
                session.Metadata.Organism;

            app.directoryModel = ...
                session.Paths.ModelDirectory;

            app.directoryExp = ...
                session.Paths.ExperimentDirectory;

            app.directoryResult = ...
                session.Paths.ResultDirectory;

        end % method applyProjectSession

        function projectDirectory = resolveProjectOpenInput(app, projectInput)

            arguments
                app
                projectInput (1, 1) string
            end

            projectDirectory = ...
                openmebius.infrastructure.project.FileProjectRepository ...
                .resolveProjectDirectory(projectInput);

        end % method resolveProjectOpenInput

        function projectInput = normalizeStartupProjectInput(~, projectInput)
            % NORMALIZESTARTUPPROJECTINPUT
            % Normalizes optional startup argument.
            %
            % Accepts:
            %   ""
            %   project directory
            %   setting.om2
            %   setting.json

            if nargin < 2 || isempty(projectInput)
                projectInput = "";
                return
            end

            try
                projectInput = string(projectInput);
            catch
                projectInput = "";
                return
            end

            if isempty(projectInput)
                projectInput = "";
                return
            end

            projectInput = strtrim(projectInput(1));

            if ismissing(projectInput)
                projectInput = "";
            end

        end % method normalizeStartupProjectInput

        function applyLegacyProjectArtifacts(app, artifacts)

            arguments
                app
                artifacts openmebius.infrastructure.legacy.LegacyProjectArtifacts
            end

            app.detachLegacyListeners();

            app.model = artifacts.Model;
            app.exp = artifacts.Experiments;
            app.batch = artifacts.Batch;
            app.result = artifacts.Result;

            app.attachLegacyListeners();

        end % method applyLegacyProjectArtifacts

        function applyTemplateModelLoadResult(app, templateModelResult)

            arguments
                app
                templateModelResult ...
                    openmebius.application.model.TemplateModelLoadResult
            end

            app.model = templateModelResult.Model;

        end % method applyTemplateModelLoadResult

        function applyExperimentImportResult(app, result)

            arguments
                app
                result openmebius.application.experiment.ExperimentImportResult
            end

            app.detachLegacyListeners();

            app.exp = result.Experiments;
            app.batch = result.Batch;

            app.attachLegacyListeners();

        end % method applyExperimentImportResult

        function applyBatchLoadResult(app, result)

            arguments
                app
                result openmebius.application.batch.BatchLoadResult
            end

            app.detachLegacyListeners();

            app.batch = result.Batch;

            app.attachLegacyListeners();

        end % method applyBatchLoadResult

        function applyResultLoadResult(app, resultLoadResult)

            arguments
                app
                resultLoadResult openmebius.application.result.ResultLoadResult
            end

            app.detachLegacyListeners();

            app.result = resultLoadResult.Result;

            app.attachLegacyListeners();

        end % method applyResultLoadResult

        function applyReportGenerationResult(app, reportGenerationResult)

            arguments
                app
                reportGenerationResult ...
                    openmebius.application.report.ReportGenerationResult
            end

            app.report = reportGenerationResult.Report;

        end % method applyReportGenerationResult

        function result = reloadExperimentState(app, options)

            arguments
                app
                options.LogMessages (1, 1) logical = true
            end

            app.ensureExperimentImportController();

            experimentLocation = ...
                openmebius.domain.experiment.ExperimentLocation ...
                .fromDirectory(app.directoryExp);

            outcome = app.ExperimentImportController.reload( ...
                experimentLocation, ...
                app.model);
            outcome.rethrowFailure();
            result = outcome.Result;

            app.renderExperimentImportResult( ...
                result, ...
                LogMessages = options.LogMessages);

        end % method reloadExperimentState

        function renderExperimentImportResult(app, result, options)

            arguments
                app
                result openmebius.application.experiment.ExperimentImportResult
                options.LogMessages (1, 1) logical = true
            end

            app.applyExperimentImportResult(result);
            app.loadExpData();
            loadBatchTable(app, reload = true);

            if options.LogMessages

                for i = 1:numel(result.Messages)
                    app.LogTextDate(result.Messages(i), "Info");
                end

            end

        end % method renderExperimentImportResult

        function applyBatchStyleRules(app, styleRules)

            if isempty(styleRules)
                return
            end

            for i = 1:numel(styleRules)

                style = app.batchStyleFromKey(styleRules(i).StyleKey);

                addStyle( ...
                    app.RunTable, ...
                    style, ...
                    'cell', ...
                    [styleRules(i).Rows, styleRules(i).Columns]);

            end

        end % method applyBatchStyleRules

        function style = batchStyleFromKey(app, styleKey)

            switch lower(string(styleKey))

                case "info"
                    style = app.styleInfoIcon;

                case "success"
                    style = app.styleSuccessIcon;

                case "warning"
                    style = app.styleWarningIcon;

                case "error"
                    style = app.styleErrorIcon;

                case "question"
                    style = app.styleQuestionIcon;

                otherwise
                    error( ...
                        "OpenMebius2:Batch:UnknownStyleKey", ...
                        "Unknown batch style key: %s", string(styleKey));
            end

        end % method batchStyleFromKey

        function style = resultStyleFromRule(app, rule)

            key = lower(string(rule.StyleKey));

            switch key

                case "chi2-passed"

                    if app.isDarkTheme()
                        style = app.styleIsPassedDark;
                    else
                        style = app.styleIsPassed;
                    end

                case "chi2-failed"

                    if app.isDarkTheme()
                        style = app.styleIsNotPassedDark;
                    else
                        style = app.styleIsNotPassed;
                    end

                case "align-right"
                    style = uistyle("HorizontalAlignment", "right");

                case "background"
                    style = uistyle("BackgroundColor", char(rule.Value));

                otherwise
                    error( ...
                        "OpenMebius2:Result:UnknownStyleKey", ...
                        "Unknown result style key: %s", key);
            end

        end % method resultStyleFromRule

        function applyResultStyleRules(app, tableObject, styleRules)

            if isempty(styleRules)
                return
            end

            for i = 1:numel(styleRules)

                style = app.resultStyleFromRule(styleRules(i));

                target = char(styleRules(i).Target);

                switch string(styleRules(i).Target)

                    case "cell"
                        addStyle( ...
                            tableObject, ...
                            style, ...
                            target, ...
                            [styleRules(i).Rows, styleRules(i).Columns]);

                    case "column"
                        addStyle( ...
                            tableObject, ...
                            style, ...
                            target, ...
                            styleRules(i).Columns);

                    case "row"
                        addStyle( ...
                            tableObject, ...
                            style, ...
                            target, ...
                            styleRules(i).Rows);

                    otherwise
                        error( ...
                            "OpenMebius2:Result:InvalidStyleTarget", ...
                            "Unknown style target: %s", string(styleRules(i).Target));
                end

            end

        end % method applyResultStyleRules

        function loadLegacyProjectObjects(app)

            app.updateStatus("model", "running");

            modelLocation = openmebius.domain.model.ModelLocation ...
                .fromDirectory(app.directoryModel);
            modelRepository = ...
                openmebius.infrastructure.model.ModelRepository();
            app.model = modelRepository.load(modelLocation);

            app.notifyInfo("Model loaded successfully.");

            app.notifyInfo("Constructing EMU network...");

            pause(0.5)

            loadEMUModel(app)

            loadPathway(app)

            app.notifyInfo("EMU network was successfully constructed.");
            app.updateStatus("model", "finished");

            app.updateStatus("experiment", "running");

            try
                result = app.reloadExperimentState(LogMessages = false);
            catch ME
                app.notifyException( ...
                    ME, ...
                    Title = "Experiment reload failed", ...
                    Alert = true);
                app.updateStatus("experiment", "error");
                return
            end

            app.updateStatus("experiment", "finished");

            for i = 1:numel(result.Messages)
                app.LogTextDate(result.Messages(i), "Info");
            end

            loadResult(app)

        end % method loadLegacyProjectObjects

        function ensureBatchPresenter(app)

            if isempty(app.BatchPresenter)
                app.BatchPresenter = ...
                    openmebius.presentation.batch.BatchPresenter();
            end

        end % method ensureBatchPresenter

        function ensureBatchRunController(app)

            if isempty(app.BatchRunController)
                app.BatchRunController = ...
                    openmebius.application.batch.BatchRunController();
            end

        end % ensureBatchRunController

        function ensureExperimentCalculationController(app)

            if isempty(app.ExperimentCalculationController)
                app.ExperimentCalculationController = ...
                    openmebius.application.experiment ...
                    .ExperimentCalculationController();
            end

        end % ensureExperimentCalculationController

        function ensureExperimentPresenter(app)

            if isempty(app.ExperimentPresenter)
                app.ExperimentPresenter = ...
                    openmebius.presentation.experiment ...
                    .ExperimentPresenter();
            end

        end % ensureExperimentPresenter

        function ensureProgressBar(app)

            if isempty(app.ProgressBar) || ~isvalid(app.ProgressBar)
                app.ProgressBar = CustomProgressBar(app.GridLayout2, 3, 1);
            end

        end % method ensureProgressBar

        function ensureResultPresenter(app)

            if isempty(app.ResultPresenter)
                app.ResultPresenter = ...
                    openmebius.presentation.result.ResultPresenter();
            end

        end % method ensureResultPresenter

        function ensureResultPlotPresenter(app)

            if isempty(app.ResultPlotPresenter)
                app.ResultPlotPresenter = ...
                    openmebius.presentation.result.ResultPlotPresenter();
            end

        end % method ensureResultPlotPresenter

        function rows = selectedTableRows(~, tableObject)

            rows = zeros(0, 1);

            try
                selection = tableObject.Selection;

                if isempty(selection)
                    return
                end

                if isvector(selection)
                    rows = selection(:);
                else
                    rows = selection(:, 1);
                end

                rows = double(rows(:));
                rows = rows(~isnan(rows));
                rows = rows(rows >= 1);
                rows = unique(rows, "stable");

                data = tableObject.Data;

                if isempty(data)
                    rows = zeros(0, 1);
                    return
                end

                if istable(data)
                    maxRow = height(data);
                else
                    maxRow = size(data, 1);
                end

                rows = rows(rows <= maxRow);

            catch
                rows = zeros(0, 1);
            end

        end % method selectedTableRows

        function fluxCells = toFluxLabelCell(~, values)

            if isempty(values)
                fluxCells = {};
                return
            end

            if iscell(values)
                fluxCells = values(:);
                return
            end

            if isnumeric(values)
                fluxCells = arrayfun( ...
                    @(x) sprintf('%.2f', x), ...
                    values(:), ...
                    'UniformOutput', false);
                return
            end

            if isstring(values)
                fluxCells = cellstr(values(:));
                return
            end

            if ischar(values)
                fluxCells = cellstr(string(values));
                return
            end

            try
                fluxCells = cellstr(string(values(:)));
            catch
                fluxCells = {};
            end

        end % method toFluxLabelCell

        function context = captureResultPlotContext(app)

            context = struct();

            context.Mode = string(app.ResultDropDown.Value);

            context.SelectedMainRows = ...
                app.selectedTableRows(app.ResultMainTable);

            context.SelectedSubRows = ...
                app.selectedTableRows(app.ResultSubTable);

            context.MainTableData = app.ResultMainTable.Data;
            context.SubTableData = app.ResultSubTable.Data;

            context.MainTableRowNames = string.empty(0, 1);
            context.SubTableRowNames = string.empty(0, 1);

            try
                context.MainTableRowNames = string(app.ResultMainTable.RowName);
            catch
                context.MainTableRowNames = string.empty(0, 1);
            end

            try
                context.SubTableRowNames = string(app.ResultSubTable.RowName);
            catch
                context.SubTableRowNames = string.empty(0, 1);
            end

        end % method captureResultPlotContext

        %% Slack notification helpers
        function ensureSlackNotifier(app)

            if isempty(app.SlackNotifier)
                app.SlackNotifier = ...
                    openmebius.infrastructure.notification.SlackWebhookNotifier();
            end

        end % method ensureSlackNotifier

        function notifySlackBatchCompleted(app, status, options)

            arguments
                app
                status (1, 1) string
                options.ErrorMessage (1, 1) string = ""
                options.DeltaTime (1, 1) duration = seconds(0)
            end

            try
                app.ensureSlackNotifier();

                if ~app.SlackNotifier.canNotify()
                    return
                end

                projectName = "";

                try

                    if ~isempty(app.ProjectSession)
                        projectName = app.ProjectSession.Metadata.Name;
                    end

                catch
                    projectName = "";
                end

                message = "Batch calculation " + status + ".";

                if options.ErrorMessage ~= ""
                    message = message + newline + "Error: " + options.ErrorMessage;
                end

                result = app.SlackNotifier.send( ...
                    message, ...
                    Title = "OpenMebius2 Batch Run", ...
                    Status = status, ...
                    ProjectName = projectName, ...
                    BatchStatus = status, ...
                    DeltaTime = options.DeltaTime);

                if result.Success
                    app.notifyInfo("Slack notification sent.");
                elseif ~result.Skipped
                    app.notifyWarning("Slack notification failed: " + result.Message);
                end

            catch ME

                try
                    app.notifyWarning( ...
                        "Slack notification skipped: " + string(ME.message));
                catch
                end

            end

        end % method notifySlackBatchCompleted

        function beginPresentationPreferences(app)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.beginPreferences(context);
            app.renderMainViewModel(viewModel);

        end % method beginPresentationPreferences

        function finishPresentationPreferences(app)

            try
                context = app.capturePresentationContext();
                viewModel = app.Presenter.finishPreferences(context);
                app.renderMainViewModel(viewModel);
            catch ME

                try
                    app.notifyWarning( ...
                        "Failed to restore main UI after Preferences: " + ...
                        string(ME.message));
                catch
                end

                app.restoreMainInteraction();
            end

        end % method finishPresentationPreferences

        %% Private initialization function
        function initLog(app)

            % Initial message
            app.LogTextArea.Value = ...
                "--------------------------------------------------------------------------------------------------------------------------------------------------------------" + newline + ...
                "OpenMebius2 is a MATLAB App for 13C metabolic flux analysis (13C-MFA) and related calculations." + newline + ...
                "Please load a project directory to start the analysis." + newline + ...
                "If you are new to OpenMebius2, please see the documentation and examples at tutorial page." + newline + newline + ...
                "You can use the following resources to try this software:" + newline + ...
                "- GitHub repository: URL" + newline + ...
                "- Tutorial page: URL" + newline + ...
                "- Article: URL" + newline + newline + ...
                "If you use OpenMebius2 in your scientific work, please consider to cite following publication: " + newline + ...
                "For more information, please visit URL" + newline + ...
                "--------------------------------------------------------------------------------------------------------------------------------------------------------------";

        end % function initLog

        function initDirectory(app, directory)
            % Initialize the results directory

            app.directoryModel = fullfile(directory, "model");
            app.directoryExp = fullfile(directory, "experiments");
            app.directoryResult = fullfile(directory, "results");

            % Create the directories if they do not exist
            if ~isfolder(app.directoryModel)
                mkdir(app.directoryModel);
            end

            if ~isfolder(app.directoryExp)
                mkdir(app.directoryExp);
            end

            if ~isfolder(app.directoryResult)
                mkdir(app.directoryResult);
            end

        end % function initResults

        function initStatusTable(app, options)
            % INITSTATUSTABLE Initialize the status component with default values

            arguments
                app
                options.update = false
                options.rows = "";
            end % arguments

            ui = app.StatusHTML;

            if app.isDarkTheme()
                css = [
                       "body { background-color: #222; color: #EEE; margin: 0; padding: 0; font-family: sans-serif; }" ...
                           "table { width: 100%; border-collapse: collapse; }" ...
                           "td { padding: 8px; background-color: #222; border: none; }" ...
                           "td:first-child { width: 10%; text-align: center; }" ...
                           "td:nth-child(2) { width: 20%; }" ...
                           "td:last-child  { width: 70%; }"
                       ];
            else
                css = [
                       "body { background-color: #fff; color: #000; margin: 0; padding: 0; font-family: sans-serif; }" ...
                           "table { width: 100%; border-collapse: collapse; }" ...
                           "td { padding: 8px; background-color: #fff; border: none; }" ...
                           "td:first-child { width: 10%; text-align: center; }" ...
                           "td:nth-child(2) { width: 20%; }" ...
                           "td:last-child  { width: 70%; }"
                       ];
            end % if app.isDarkTheme()

            cssChar = char(strjoin(css, ""));
            htmlHeader = ['<html><head><style>', cssChar, '</style></head><body>'];
            htmlHeader = [htmlHeader, '<table>'];
            htmlFooter = '</table></body></html>';

            if options.update

                % If the table is initialized, we will update it
                rows = options.rows;

                if isempty(rows)
                    rows = {
                            '<tr><td></td><td>Model</td><td>Initializing...</td></tr>', ...
                                '<tr><td></td><td>Experiment</td><td>Initializing...</td></tr>', ...
                                '<tr><td></td><td>Batch</td><td>Initializing...</td></tr>', ...
                                '<tr><td></td><td>Result</td><td>Initializing...</td></tr>'
                            };
                end % if isempty(rows)

            else

                rows = {
                        '<tr><td>ℹ️</td><td>Model</td><td>Not loaded</td></tr>', ...
                            '<tr><td>ℹ️</td><td>Experiment</td><td>Not loaded</td></tr>', ...
                            '<tr><td>ℹ️</td><td>Batch</td><td>Not started</td></tr>', ...
                            '<tr><td>ℹ️</td><td>Result</td><td>Not loaded</td></tr>'
                        };

            end % if options.update

            htmlBody = strjoin(rows, "");
            htmlContent = [htmlHeader, htmlBody, htmlFooter];

            if ~ischar(htmlContent)
                htmlContent = char(htmlContent);
            end % if ~ischar(htmlContent)

            ui.HTMLSource = htmlContent;

            if ~options.update

                % Set the initial status
                app.calcStatus = ...
                    openmebius.presentation.status.StatusPresenter.initial();

            end

        end % function initStatusTable

        function setLogFile(app)

            try
                openmebius.infrastructure.logging.Logger ...
                    .configureDefaultDiary();
            catch ME
                msg = "Could not set log file." + newline + ...
                    string(ME.message);
                app.LogTextDate(msg, "Error");
            end

        end % function setLogFile

        %% Private load function
        function loadHistory(app)

            system = System();
            directoryLog = system.getCacheDirectory();

            if ~isfolder(directoryLog)
                mkdir(directoryLog);
                return
            end

            fileLog = fullfile(directoryLog, 'history.mat');

            if isfile(fileLog)
                load(fileLog, 'projectHistory', 'tempModelHistory');
                app.ProjectDirectoryDropDown.Items = projectHistory;
                app.TemplateModelDirectoryDropDown.Items = tempModelHistory;
                app.ProjectDirectoryDropDown.Value = "";
                app.TemplateModelDirectoryDropDown.Value = "";
            end

        end % method loadHistory

        function loadTable(~, tableObject, tableData, options)

            arguments
                ~
                tableObject
                tableData table
                options.ColumnEditable = false(1, width(tableData))
            end

            tableObject.Data = tableData;
            tableObject.ColumnName = tableData.Properties.VariableNames;
            tableObject.RowName = tableData.Properties.RowNames;
            tableObject.ColumnEditable = options.ColumnEditable;

        end % function loadTable

        function loadEMUModel(app)

            loadModelTable(app)
            loadMSTable(app)
            loadBiomassTable(app)

        end % function loadEMUModel

        function loadModelTable(app, options)

            arguments
                app
                options.ColumnEditable = false(1, width(app.model.getModelTableGUI()))
            end

            tableModel = app.model.getModelTableGUI();
            loadTable(app, app.ModelTable, tableModel, ColumnEditable = options.ColumnEditable);
            errorRows = app.model.getInvalidModelRowIdx();

            resetModelTableColorFormat(app)

            if ~isempty(errorRows)
                addStyle(app.ModelTable, app.styleError, 'row', errorRows);
            end

        end % function loadModelTable

        function loadMSTable(app, options)

            arguments
                app
                options.isColumnEditable = false
            end

            isColumnEditable = options.isColumnEditable;

            % MS data table
            tableMS = app.model.getMSTable();

            if isColumnEditable
                columnEditable = true(1, width(tableMS));
            else
                columnEditable = false(1, width(tableMS));
            end

            loadTable(app, app.MSTable, tableMS, ColumnEditable = columnEditable);
            errorRows = app.model.getInvalidMSRowIdx();

            resetMSTableColorFormat(app);

            if ~isempty(errorRows)
                addStyle(app.MSTable, app.styleError, 'row', errorRows);
            end

            % MS atom table
            tableAtom = app.model.getAtomTable();

            if isColumnEditable
                columnEditable = true(1, width(tableAtom));
            else
                columnEditable = false(1, width(tableAtom));
            end

            loadTable(app, app.AtomTable, tableAtom, ColumnEditable = columnEditable);
            errorRows = app.model.getInvalidAtomRowIdx();

            if ~isempty(errorRows)
                addStyle(app.AtomTable, app.styleError, 'row', errorRows);
            end

        end % function loadMSTable

        function loadBiomassTable(app)

            % Biomass data table
            tableBiomass = app.model.getBiomassTable();
            loadTable(app, app.BiomassTable, tableBiomass);

        end % function loadBiomassTable

        function loadTracerTable(app)

            tableTracer = app.exp.getTracerTable();

            app.LabelTable.Data = tableTracer;
            app.LabelTable.ColumnName = tableTracer.Properties.VariableNames;
            app.LabelTable.RowName = tableTracer.Properties.RowNames;

        end % function loadTracerTable

        function loadUptakeTable(app)

            % Uptake data table
            tableUptake = app.exp.getUptakeTable();

            app.UptakeTable.Data = tableUptake;
            app.UptakeTable.ColumnName = tableUptake.Properties.VariableNames;
            app.UptakeTable.RowName = tableUptake.Properties.RowNames;
            app.UptakeTable.ColumnEditable = true(1, size(tableUptake, 2));

        end % function loadUptakeTable

        function loadPathway(app)

            if ~app.model.isPathwayLoaded
                msg = "Pathway not loaded";
                LogTextDate(app, msg, "Error");
                return
            end

            isDark = isDarkTheme(app);

            drawPathway( ...
                app.model, ...
                app.MainUIAxes, ...
                app.ContextMenu, ...
                darkmode = isDark ...
            );

            app.MainUIAxes.ContextMenu = app.ContextMenu;

            msg = openmebius.infrastructure.logging.Logger ...
                .formatDatedMessage("Pathway loaded successfully", "Info");
            app.LogText(msg);

        end % function loadPathway

        function loadExpData(app)

            loadExpTable(app)
            loadTracerTable(app)
            loadUptakeTable(app)

        end % function loadExpData

        function loadExpTable(app)

            tableExps = getInfoTable(app.exp);
            app.ExpTable.Data = tableExps;
            app.ExpTable.ColumnName = tableExps.Properties.VariableNames;
            app.ExpTable.RowName = tableExps.Properties.RowNames;
            app.ExpTable.ColumnEditable = true(1, size(tableExps, 2));

        end % function loadExpTable

        function loadBatchTable(app, options)

            arguments
                app
                options.reload = false
            end

            app.ensureBatchPresenter();

            if ~options.reload

                updateStatus(app, "batch", "running");

                app.ensureBatchLoadService();

                experimentLocation = ...
                    openmebius.domain.experiment.ExperimentLocation ...
                    .fromDirectory(app.directoryExp);

                result = app.BatchLoadService.loadForExperiment( ...
                    experimentLocation, ...
                    app.exp);

                app.applyBatchLoadResult(result);

            end

            if isempty(app.batch) || ~isvalid(app.batch)
                msg = "Batch object is not valid.";
                app.notifyError(msg);
                app.updateStatus("batch", "error");
                return
            end

            app.ensureProgressBar();

            viewModel = app.BatchPresenter.presentTable(app.batch);

            app.renderBatchTable(viewModel);

            if ~options.reload
                app.updateStatus("batch", "finished");
                app.notifyInfo("Batch table loaded successfully.");
            end

        end % function loadBatchTable

        function loadResult(app, options)

            arguments
                app
                options.reload = false
            end

            app.ensureResultPresenter();

            if ~options.reload

                app.updateStatus("result", "running");

                app.ensureResultLoadService();

                resultLocation = openmebius.domain.result.ResultLocation ...
                    .fromDirectory(app.directoryResult);

                resultLoadResult = app.ResultLoadService.load(resultLocation);

                app.applyResultLoadResult(resultLoadResult);

            end

            if isempty(app.result) || ~isvalid(app.result)
                msg = "Result object is not valid.";
                app.notifyError(msg);
                app.updateStatus("result", "error");
                return
            end

            viewModel = app.ResultPresenter.presentIndex( ...
                app.result, ...
                app.batch);

            app.renderResultSubTable(viewModel);

            app.renderResultMainTable( ...
                openmebius.presentation.result.ResultTableViewModel());

            if isempty(viewModel.Data)

                if ~options.reload
                    app.updateStatus("result", "init");
                    app.notifyInfo("No result files found in the results directory.");
                end

                return
            end

            if ~options.reload
                app.updateStatus("result", "finished");
                app.notifyInfo("Result files loaded successfully.");
            end

        end % method loadResult

        function loadMainResultTable(app, options)

            arguments
                app
                options.relative = false
                options.relativeTo = ""
            end

            type = string(app.ResultDropDown.Value);
            rows = app.selectedTableRows(app.ResultSubTable);

            if isempty(rows)
                return
            end

            switch type

                case "Overview"

                    row = rows(1);
                    batchID = string(app.ResultSubTable.Data.ID(row));

                    loadResultOverView( ...
                        app, ...
                        batchID, ...
                        relative = options.relative, ...
                        relativeTo = options.relativeTo);

                case {"Details", "Detailed"}

                    row = rows(1);
                    batchID = string(app.ResultSubTable.Data.ID(row));

                    loadResultDetailed(app, batchID);

                case "Comparison"

                    if numel(rows) < 2
                        msg = "Please select at least two results for comparison.";
                        LogTextDate(app, msg, "Warning");
                        return
                    end

                    batchIDs = string(app.ResultSubTable.Data.ID(rows));
                    names = string(app.ResultSubTable.Data.Name(rows));

                    loadResultComparison( ...
                        app, ...
                        batchIDs, ...
                        names, ...
                        relative = options.relative, ...
                        relativeTo = options.relativeTo);

                otherwise

                    error( ...
                        "OpenMebius2:Result:InvalidViewMode", ...
                        "Unknown result view mode: %s", type);
            end

        end % method loadMainResultTable

        function loadResultOverView(app, batchID, options)

            arguments
                app
                batchID string
                options.relative = false
                options.relativeTo = ""
            end

            app.ensureResultPresenter();

            viewModel = app.ResultPresenter.presentMain( ...
                app.result, ...
                "Overview", ...
                batchID, ...
                "", ...
                Relative = options.relative, ...
                RelativeTo = options.relativeTo, ...
                IsDarkTheme = app.isDarkTheme());

            app.renderResultMainTable(viewModel);

        end % method loadResultOverView

        function loadResultDetailed(app, batchID)

            app.ensureResultPresenter();

            viewModel = app.ResultPresenter.presentMain( ...
                app.result, ...
                "Details", ...
                batchID, ...
                "", ...
                IsDarkTheme = app.isDarkTheme());

            app.renderResultMainTable(viewModel);

        end % method loadResultDetailed

        function loadResultComparison(app, batchIDs, names, options)

            arguments
                app
                batchIDs string
                names string
                options.relative = false
                options.relativeTo = ""
            end

            app.ensureResultPresenter();

            viewModel = app.ResultPresenter.presentMain( ...
                app.result, ...
                "Comparison", ...
                batchIDs, ...
                names, ...
                Relative = options.relative, ...
                RelativeTo = options.relativeTo, ...
                IsDarkTheme = app.isDarkTheme());

            app.renderResultMainTable(viewModel);

        end % method loadResultComparison

        function loadSubResultTable(app, ~, ~, ~, ~)

            app.ensureResultPresenter();

            viewModel = app.ResultPresenter.presentIndex( ...
                app.result, ...
                app.batch);

            app.renderResultSubTable(viewModel);

        end % method loadSubResultTable

        %% Private reset function
        function resetAllComponents(app)
            % RESETALLCOMPONENTS Reset all table data

            % Reset table data
            app.ModelTable.Data = [];
            app.MSTable.Data = [];
            app.AtomTable.Data = [];
            app.ExpTable.Data = [];
            app.BiomassTable.Data = [];
            app.UptakeTable.Data = [];
            app.LabelTable.Data = [];
            app.RunTable.Data = [];
            app.ResultMainTable.Data = [];
            app.ResultSubTable.Data = [];

            % Reset table column names
            app.ModelTable.ColumnName = [];
            app.MSTable.ColumnName = [];
            app.AtomTable.ColumnName = [];
            app.ExpTable.ColumnName = [];
            app.BiomassTable.ColumnName = [];
            app.UptakeTable.ColumnName = [];
            app.LabelTable.ColumnName = [];
            app.RunTable.ColumnName = [];
            app.ResultMainTable.ColumnName = [];
            app.ResultSubTable.ColumnName = [];

            % Reset table row names
            app.ModelTable.RowName = [];
            app.MSTable.RowName = [];
            app.AtomTable.RowName = [];
            app.ExpTable.RowName = [];
            app.BiomassTable.RowName = [];
            app.UptakeTable.RowName = [];
            app.LabelTable.RowName = [];
            app.RunTable.RowName = [];
            app.ResultMainTable.RowName = [];
            app.ResultSubTable.RowName = [];

            % Reset figure and plot
            cla(app.MainUIAxes);
            app.MainUIAxes.XLim = [0 1];
            app.MainUIAxes.YLim = [0 1];
            app.MainUIAxes.XTick = [];
            app.MainUIAxes.YTick = [];
            app.MainUIAxes.XLabel.String = "";
            app.MainUIAxes.YLabel.String = "";
            app.MainUIAxes.Title.String = "";
            app.MainUIAxes.XLabel.Visible = 'off';
            app.MainUIAxes.YLabel.Visible = 'off';
            app.MainUIAxes.Title.Visible = 'off';
            app.MainUIAxes.XColor = 'none';
            app.MainUIAxes.YColor = 'none';
            app.MainUIAxes.XGrid = 'off';
            app.MainUIAxes.YGrid = 'off';

            % Reset subplot
            app.SubUIAxes.XLim = [0 1];
            app.SubUIAxes.YLim = [0 1];
            app.SubUIAxes.XTick = [];
            app.SubUIAxes.YTick = [];
            app.SubUIAxes.XLabel.String = "";
            app.SubUIAxes.YLabel.String = "";
            app.SubUIAxes.Title.String = "";
            app.SubUIAxes.XLabel.Visible = 'off';
            app.SubUIAxes.YLabel.Visible = 'off';
            app.SubUIAxes.Title.Visible = 'off';
            app.SubUIAxes.XColor = 'none';
            app.SubUIAxes.YColor = 'none';
            app.SubUIAxes.XGrid = 'off';
            app.SubUIAxes.YGrid = 'off';

        end % method resetAllComponents

        function resetTableColorFormat(app)

            resetModelTableColorFormat(app);
            resetMSTableColorFormat(app);

        end % function resetTableColorFormat

        function resetModelTableColorFormat(app)
            removeStyle(app.ModelTable);
        end

        function resetMSTableColorFormat(app)
            removeStyle(app.MSTable);
            removeStyle(app.AtomTable);
        end

        function resetResultTableColorFormat(app)
            removeStyle(app.ResultSubTable);
            removeStyle(app.ResultMainTable);
        end

        %% Private update function
        function [icon, text] = updateStatusModel(~, status)
            % UPDATESTATUSMODEL Update the model status in the status table

            arguments
                ~
                status string {mustBeMember(status, ["init", "running", "finished", "error"])}
            end

            switch status

                case "init"
                    icon = "ℹ️";
                    text = "Model not loaded";

                case "running"
                    icon = "⏳";
                    text = "Loading model...";

                case "finished"
                    icon = "✅";
                    text = "Model loaded successfully";

                case "error"
                    icon = "❌";
                    text = "Error loading model";

            end % switch status

        end % function updateStatusModel

        function [icon, text] = updateStatusExperiment(~, status)
            % UPDATESTATUSEXPERIMENT Update the experiment status in the status table

            arguments
                ~
                status string {mustBeMember(status, ["init", "running", "finished", "error"])}
            end

            switch status

                case "init"
                    icon = "ℹ️";
                    text = "Experiment data not loaded";

                case "running"
                    icon = "⏳";
                    text = "Loading experiment data...";

                case "finished"
                    icon = "✅";
                    text = "Experiment data loaded successfully";

                case "error"
                    icon = "❌";
                    text = "Error loading experiment data";

            end % switch status

        end % function updateStatusExperiment

        function [icon, text] = updateStatusBatch(~, status)
            % UPDATESTATUSBATCH Update the batch status in the status table

            arguments
                ~
                status string {mustBeMember(status, ["init", "running", "finished", "error"])}
            end

            switch status

                case "init"
                    icon = "ℹ️";
                    text = "Batch not started";

                case "running"
                    icon = "⏳";
                    text = "Running batch...";

                case "finished"
                    icon = "✅";
                    text = "Batch run completed successfully";

                case "error"
                    icon = "❌";
                    text = "Error in batch run";

            end % switch status

        end % function updateStatusBatch

        function [icon, text] = updateStatusResult(~, status)
            % UPDATESTATUSRESULT Update the result status in the status table

            arguments
                ~
                status string {mustBeMember(status, ["init", "running", "finished", "error"])}
            end

            switch status

                case "init"
                    icon = "ℹ️";
                    text = "Result not loaded";

                case "running"
                    icon = "⏳";
                    text = "Loading result...";

                case "finished"
                    icon = "✅";
                    text = "Result loaded successfully";

                case "error"
                    icon = "❌";
                    text = "Error loading result";

            end % switch status

        end % function updateStatusResult

        function updateResult(app, ~)

            loadResult(app, reload = true);

            % Drawnow
            drawnow;

        end % function updateResult

        function updateResultPlot(app)

            type = string(app.ResultDropDown.Value);

            % During the migration period, pathway plot is supported only for
            % Overview. Details / Comparison will be handled later by
            % ResultPlotPresenter.
            if type == "Details" || type == "Detailed" || type == "Comparison"
                cla(app.SubUIAxes);

                % Main pathway should not be overwritten by unsupported plots.
                % If you prefer clearing both axes, uncomment the next line.
                % cla(app.MainUIAxes);

                return
            end

            if type ~= "Overview"
                return
            end

            selectedResultRows = app.selectedTableRows(app.ResultSubTable);
            selectedFluxRows = app.selectedTableRows(app.ResultMainTable);

            if isempty(selectedResultRows)
                return
            end

            if isempty(app.ResultSubTable.Data) || ~istable(app.ResultSubTable.Data)
                return
            end

            tableData = app.getResultMainRawData();

            if isempty(tableData) || ~istable(tableData)
                return
            end

            if ~any(string(tableData.Properties.VariableNames) == "Flux")
                return
            end

            rxnIDs = app.getResultReactionIds(tableData);

            if isempty(rxnIDs)
                return
            end

            % -------------------------------------------------------------
            % Draw metabolic pathway with flux labels.
            % This should work even when no flux row is selected.
            % -------------------------------------------------------------
            fluxColumn = tableData.Flux;

            if numel(fluxColumn) > 1
                fluxColumn = fluxColumn(1:end - 1);
            end

            fluxLabels = app.toFluxLabelCell(fluxColumn);

            modelTable = getModelTable(app.model);
            highlightMask = false(height(modelTable), 1);

            hasSelectedFlux = ~isempty(selectedFluxRows);

            if hasSelectedFlux
                selectedFluxRow = selectedFluxRows(1);

                if selectedFluxRow <= numel(rxnIDs)
                    rxnID = string(rxnIDs(selectedFluxRow));
                    highlightMask = strcmp(modelTable.Properties.RowNames, rxnID);
                else
                    hasSelectedFlux = false;
                end

            end

            app.MainUIAxes.Visible = 'on';
            app.SubUIAxes.Visible = 'on';

            drawFluxLabel( ...
                app.model, ...
                app.MainUIAxes, ...
                fluxLabels, ...
                highlight = highlightMask, ...
                darkmode = isDarkTheme(app));

            app.MainUIAxes.ContextMenu = app.ContextMenu;

            % -------------------------------------------------------------
            % CI plot requires a selected reaction.
            % If no reaction is selected, keep pathway and clear only SubUIAxes.
            % -------------------------------------------------------------
            if ~hasSelectedFlux
                cla(app.SubUIAxes);
                return
            end

            selectedResultRow = selectedResultRows(1);

            if selectedResultRow > height(app.ResultSubTable.Data)
                cla(app.SubUIAxes);
                return
            end

            batchID = string(app.ResultSubTable.Data.ID(selectedResultRow));
            rxnID = string(rxnIDs(selectedFluxRows(1)));

            data = getCIReaction(app.result, batchID, rxnID);

            if isempty(data) || ~isfield(data, 'CI')
                cla(app.SubUIAxes);
                return
            end

            cla(app.SubUIAxes);

            drawCIReaction( ...
                app.result, ...
                app.SubUIAxes, ...
                data);

        end % method updateResultPlot

        %% Private clipboard function
        function clipboardText = copyTableToClipboard(~, tableObject)
            % COPYTABLETOCLIPBOARD Copy the specified table content to clipboard

            data = tableObject.Data;
            [numRows, numCols] = size(data);

            % Copy only selected range if available (rectangular bounding box)
            r1 = 1; r2 = numRows;
            c1 = 1; c2 = numCols;

            if isprop(tableObject, 'Selection')
                sel = tableObject.Selection;

                if ~isempty(sel) && size(sel, 2) >= 2
                    rows = sel(:, 1);
                    cols = sel(:, 2);
                    r1 = double(max(1, min(rows, [], "all")));
                    r2 = double(min(numRows, max(rows, [], "all")));
                    c1 = double(max(1, min(cols, [], "all")));
                    c2 = double(min(numCols, max(cols, [], "all")));
                end

            end

            numRowsOut = max(0, r2 - r1 + 1);
            lines = strings(numRowsOut, 1);

            TAB = char(9);
            CRLF = char([13 10]);

            outRow = 0;

            r1 = r1(1); r2 = r2(1);
            c1 = c1(1); c2 = c2(1);

            for i = r1:r2

                outRow = outRow + 1;

                rowCells = strings(1, max(0, c2 - c1 + 1));

                outCol = 0;

                for j = c1:c2

                    outCol = outCol + 1;

                    if istable(data) || iscell(data)
                        v = data{i, j};
                    else
                        v = data(i, j);
                    end

                    rowCells(outCol) = localToStringScalar(v);
                end

                lines(outRow) = strjoin(rowCells, TAB);

            end

            clipboardText = strjoin(lines, CRLF);

            % Copy as char to keep Excel-friendly plain text
            clipboard('copy', char(clipboardText));

            function s = localToStringScalar(v)
                % Convert table cell content into a scalar string.
                % Treat missing/empty as "" to avoid propagating <missing>.
                if iscell(v)

                    if isempty(v)
                        s = "";
                        return;
                    end

                    if isscalar(v)
                        s = localToStringScalar(v{1});
                        return;
                    end

                    s = localToStringScalar(v{1});
                    return;
                end

                if isstring(v)

                    if isempty(v)
                        s = "";
                        return;
                    end

                    s = v(1);

                    if ismissing(s)
                        s = "";
                    end

                    return;
                end

                if ischar(v)
                    s = string(v);
                    return;
                end

                if ismissing(v)
                    s = "";
                    return;
                end

                try
                    s = string(v);

                    if ismissing(s)
                        s = "";
                    end

                catch
                    s = "";
                end

            end

        end % function copyTableToClipboard

        function pasteClipboardToTable(~, tableObject)
            % PASTECLIPBOARDTOTABLE Paste clipboard content to the specified table

            clipboardContent = clipboard('paste');
            rows = regexp(clipboardContent, '\r\n|\n|\r', 'split');

            if ~isempty(rows) && isempty(rows{end})
                rows(end) = [];
            end

            numRows = length(rows);

            data = tableObject.Data;
            isTableData = istable(data);

            % Paste starting position (top-left of current selection)
            startRow = 1;
            startCol = 1;

            if isprop(tableObject, 'Selection')
                sel = tableObject.Selection;

                if ~isempty(sel) && size(sel, 2) >= 2
                    startRow = max(1, min(sel(:, 1)));
                    startCol = max(1, min(sel(:, 2)));
                end

            end

            if isTableData
                maxRows = height(data);
                maxCols = width(data);
            else
                maxRows = size(tableObject.Data, 1);
                maxCols = size(tableObject.Data, 2);
            end

            for i = 1:numRows

                if isempty(rows{i})
                    continue
                end

                cols = regexp(rows{i}, '\t', 'split');
                numCols = length(cols);

                for j = 1:numCols

                    targetRow = startRow + (i - 1);
                    targetCol = startCol + (j - 1);

                    if targetRow > maxRows || targetCol > maxCols
                        continue
                    end

                    token = cols{j};

                    if ischar(token)
                        token = strrep(token, char(13), '');
                    end

                    if isTableData
                        existing = data{targetRow, targetCol};

                        if isnumeric(existing)
                            v = str2double(token);

                            if isnan(v)
                                v = NaN;
                            end

                            data{targetRow, targetCol} = v;

                        elseif islogical(existing)
                            t = strtrim(token);
                            data{targetRow, targetCol} = any(strcmpi(t, {'1', 'true', 't', 'yes', 'y'}));

                        else
                            data{targetRow, targetCol} = string(token);
                        end

                    else
                        tableObject.Data{targetRow, targetCol} = token;
                    end

                end

            end

            if isTableData
                tableObject.Data = data;
            end

        end % function pasteClipboardToTable

        %% Style functions
        function setBatchInitialStyle(app, status)

            app.ensureBatchPresenter();

            if isempty(app.RunTable.Data)
                return
            end

            styleRules = ...
                app.BatchPresenter.styleRulesForStatus( ...
                app.RunTable.Data, ...
                status);

            app.applyBatchStyleRules(styleRules);

        end

        function data = getResultMainRawData(app)

            data = app.ResultMainTable.Data;

            try
                userData = app.ResultMainTable.UserData;

                if isstruct(userData) && ...
                        isfield(userData, "RawData") && ...
                        ~isempty(userData.RawData)

                    data = userData.RawData;
                end

            catch
                data = app.ResultMainTable.Data;
            end

        end % method getResultMainRawData

        function rxnIDs = getResultReactionIds(app, tableData)

            rxnIDs = strings(0, 1);

            try
                rxnIDs = string(app.ResultMainTable.RowName);
                rxnIDs = rxnIDs(:);

                if ~isempty(rxnIDs) && any(rxnIDs ~= "")
                    return
                end

            catch
            end

            try
                rxnIDs = string(tableData.Properties.RowNames);
                rxnIDs = rxnIDs(:);

                if ~isempty(rxnIDs) && any(rxnIDs ~= "")
                    return
                end

            catch
            end

            candidates = ["ID", "RxnID", "Reaction", "ReactionID"];

            try
                names = string(tableData.Properties.VariableNames);

                for i = 1:numel(candidates)
                    idx = find(names == candidates(i), 1);

                    if ~isempty(idx)
                        rxnIDs = string(tableData{:, idx});
                        rxnIDs = rxnIDs(:);
                        return
                    end

                end

            catch
            end

        end % method getResultReactionIds

        %% Status function
        function statusBatch(app, data)

            app.ensureBatchPresenter();

            viewModel = ...
                app.BatchPresenter.presentProgress( ...
                data, ...
                app.RunTable.Data);

            if ~isempty(viewModel.Notification)
                app.showNotification(viewModel.Notification);
            end

            app.applyBatchStyleRules(viewModel.StyleRules);

            app.renderBatchProgress(viewModel);

        end % function statusBatch

        function statusGeneralMsg(app, event)

            try
                [status, msg] = app.extractGeneralMessagePayload(event);

                notification = ...
                    openmebius.presentation.notification.Notification( ...
                    msg, ...
                    status);

                app.showNotification(notification);

            catch ME
                % Event callback内で例外を握り潰すと通知消失に見えるため、
                % 最低限LogTextAreaへ落とす。
                try
                    app.appendLogText( ...
                        openmebius.infrastructure.logging.Logger ...
                        .formatDatedMessage( ...
                        "Failed to handle GeneralMsg event: " + ...
                        string(ME.message), ...
                    "Error"));
                catch
                    disp(ME.message)
                end

            end

        end % method statusGeneralMsg

        function checkLatestVersionOnStartup(app)
            % CHECKLATESTVERSIONONSTARTUP Checks whether a newer version exists.
            % Update failures must not prevent application startup.

            try
                currentVersion = System.getCurrentVersion();
                [latestVersion, releaseURL] = System.getLatestOpenMebius2Version();

                if System.isVersionNewer(latestVersion, currentVersion)
                    msg = "A newer OpenMebius2 version is available: " + latestVersion + ...
                        " (current: " + currentVersion + ").";
                    app.LogTextDate(msg, "Warning");

                    answer = uiconfirm(app.OpenMebius2UIFigure, ...
                        char(msg + newline + "Open the GitHub releases page?"), ...
                        'Update available', ...
                        'Options', {'Open releases', 'Later'}, ...
                        'DefaultOption', 1, ...
                        'CancelOption', 2, ...
                        'Icon', 'warning');

                    if strcmp(answer, 'Open releases')
                        web(char(releaseURL), '-browser');
                    end

                else
                    msg = "OpenMebius2 is up to date: " + currentVersion + ".";
                    app.LogTextDate(msg, "Info");
                end

            catch ME
                msg = "Unable to check for OpenMebius2 updates: " + string(ME.message);
                app.LogTextDate(msg, "Warning");
            end

        end % method checkLatestVersionOnStartup

    end % methods (Access = private)

    %% Private callback functions
    methods (Access = private)

        function onPreferencesClosed(app, src, event)

            app.detachPreferencesListeners();
            app.PreferencesApp = [];
            app.finishPresentationPreferences();

        end % method onPreferencesClosed

    end % methods (Access = private)

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filepath)

            app.setLogFile();

            app.DialogService = ...
                openmebius.presentation.dialog.AppDialogService( ...
                app.OpenMebius2UIFigure);

            app.ProjectRepository = ...
                openmebius.infrastructure.project.FileProjectRepository();

            app.OpenProjectUseCase = ...
                openmebius.application.project.OpenProjectUseCase( ...
                app.ProjectRepository);
            app.CreateProjectUseCase = ...
                openmebius.application.project.CreateProjectUseCase( ...
                app.ProjectRepository);

            app.TemplateModelLoadService = ...
                openmebius.application.model.TemplateModelLoadService();
            app.ExperimentImportController = ...
                openmebius.application.experiment ...
                .ExperimentImportController();
            app.BatchLoadService = ...
                openmebius.application.batch.BatchLoadService();
            app.BatchRunController = ...
                openmebius.application.batch.BatchRunController();
            app.ResultLoadService = ...
                openmebius.application.result.ResultLoadService();
            app.ResultExportService = ...
                openmebius.application.result.ResultExportService();
            app.ReportGenerationService = ...
                openmebius.application.report.ReportGenerationService();
            app.ExperimentCalculationController = ...
                openmebius.application.experiment ...
                .ExperimentCalculationController();
            app.ExperimentEditController = ...
                openmebius.application.experiment ...
                .ExperimentEditController();

            app.LegacyProjectLoader = ...
                openmebius.infrastructure.legacy.LegacyProjectLoader();
            app.LegacyProjectInitializer = ...
                openmebius.infrastructure.legacy.LegacyProjectInitializer();

            app.BatchPresenter = ...
                openmebius.presentation.batch.BatchPresenter();
            app.ExperimentPresenter = ...
                openmebius.presentation.experiment.ExperimentPresenter();
            app.ResultPresenter = ...
                openmebius.presentation.result.ResultPresenter();
            app.ResultPlotPresenter = ...
                openmebius.presentation.result.ResultPlotPresenter();

            app.SlackNotifier = ...
                openmebius.infrastructure.notification.SlackWebhookNotifier();

            if nargin < 2
                filepath = "";
            else
                filepath = string(filepath);
            end

            loadHistory(app)

            initLog(app)

            initStatusTable(app);

            app.initializePresentation();

            checkLatestVersionOnStartup(app);

            filepath = app.normalizeStartupProjectInput(filepath);

            if filepath ~= ""

                try
                    projectDirectory = app.resolveProjectOpenInput(filepath);

                    if projectDirectory ~= ""
                        app.ProjectDirectoryDropDown.Value = projectDirectory;
                        ProjectLoadButtonPushed(app);
                    end

                catch ME
                    app.notifyException( ...
                        ME, ...
                        Title = "Project open failed", ...
                        Alert = true);
                end

            end

        end

        % Close request function: OpenMebius2UIFigure
        function OpenMebius2UIFigureCloseRequest(app, event)

            saveHistory(app)
            delete(app)

        end

        % Button pushed function: ProjectBrowseButton
        function ProjectBrowseButtonPushed(app, event)

            [projectDirectory, isOK] = app.uiGetDirWrap( ...
                Title = "Select Project Directory", ...
                StartPath = app.ProjectDirectoryDropDown.Value);

            if ~isOK
                return
            end

            % If the directory is equal to the current directory, do nothing
            if strcmp(app.ProjectDirectoryDropDown.Value, projectDirectory)
                return;
            end

            % Update the dropdown value
            app.ProjectDirectoryDropDown.Value = projectDirectory;

            % Add the directory to the item
            if ~any(strcmp(app.ProjectDirectoryDropDown.Items, projectDirectory))
                app.ProjectDirectoryDropDown.Items{end + 1} = projectDirectory;
            end

            % Update the status
            updateStatus(app, "model", "init");

            app.refreshPresentation();

        end

        % Button pushed function: ProjectLoadButton
        function ProjectLoadButtonPushed(app, event)

            cleanupPresentation = app.beginPresentationOperation(); %#ok<NASGU>

            try
                projectDirectory = app.resolveProjectOpenInput( ...
                    string(app.ProjectDirectoryDropDown.Value));

                session = app.OpenProjectUseCase.execute(projectDirectory);

                artifacts = app.LegacyProjectLoader.load(session);

                app.applyProjectSession(session);
                app.applyLegacyProjectArtifacts(artifacts);

                for i = 1:numel(artifacts.Messages)
                    app.notifyInfo(artifacts.Messages(i));
                end

                app.renderLegacyProjectArtifacts();

                app.refreshPresentation();

            catch ME
                app.updateStatus("model", "error");
                app.notifyException( ...
                    ME, ...
                    Title = "Project load failed", ...
                    Alert = true);
            end

        end

        % Button pushed function: ProjectSaveButton
        function ProjectSaveButtonPushed(app, event)

            try

                if isempty(app.ProjectSession) || ~isvalid(app.ProjectSession)

                    projectDirectory = app.resolveProjectOpenInput( ...
                        string(app.ProjectDirectoryDropDown.Value));

                    app.ProjectSession = app.OpenProjectUseCase.execute( ...
                        projectDirectory);
                end

                metadata = openmebius.domain.project.ProjectMetadata( ...
                    Name = string(app.ProjectNameEditField.Value), ...
                    Author = string(app.ProjectAuthorEditField.Value), ...
                    Organism = string(app.OrganismEditField.Value));

                session = openmebius.domain.project.ProjectSession( ...
                    metadata, ...
                    app.ProjectSession.Paths);

                app.ProjectSession = session;

                app.ProjectRepository.saveProject(app.ProjectSession);

                msg = "Project setting saved to " + ...
                    app.ProjectSession.Paths.SettingFile + ...
                    " and " + ...
                    app.ProjectSession.Paths.LegacySettingFile;

                app.notifyInfo(msg);

            catch ME
                app.notifyException( ...
                    ME, ...
                    Title = "Project save failed", ...
                    Alert = true);
            end

        end

        % Button pushed function: ProjectCreateButton
        function ProjectCreateButtonPushed(app, event)

            cleanupPresentation = app.beginPresentationOperation(); %#ok<NASGU>

            [answ, ok] = app.uiInputDlgWrap( ...
                Prompt = "Enter the name of the new project directory:", ...
                Title = "Create Project Directory", ...
                Default = "NewProject" ...
            );

            if ok
                directoryName = answ(1); % string
            else
                directoryName = ""; % cancel時
            end

            if isempty(directoryName)
                return; % User canceled the dialog
            end % if isempty(directoryName)

            directoryName = directoryName{1};

            if isempty(directoryName)
                app.notifyError("Project directory name cannot be empty.");
                return
            end % if isempty(directoryName)

            projectParentDirectory = app.uiGetDirWrap( ...
                StartPath = app.ProjectDirectoryDropDown.Value, ...
                Title = "Select Parent Directory for New Project" ...
            );

            if isequal(projectParentDirectory, 0) || ...
                    (isstring(projectParentDirectory) && strlength(projectParentDirectory) == 0)
                return;
            end

            projectParentDirectory = string(projectParentDirectory);
            templateModelDirectory = string( ...
                app.TemplateModelDirectoryDropDown.Value);

            try
                app.ensureProjectServices();
                app.ensureLegacyProjectInitializer();

                metadata = openmebius.domain.project.ProjectMetadata( ...
                    Name = string(app.ProjectNameEditField.Value), ...
                    Author = string(app.ProjectAuthorEditField.Value), ...
                    Organism = string(app.OrganismEditField.Value));

                createResult = app.CreateProjectUseCase.execute( ...
                    ParentDirectory = projectParentDirectory, ...
                    ProjectDirectoryName = string(directoryName), ...
                    TemplateModelDirectory = templateModelDirectory, ...
                    Metadata = metadata);

                app.applyProjectSession(createResult.Session);

                items = string(app.ProjectDirectoryDropDown.Items);

                if ~any(items == createResult.Session.Paths.RootDirectory)
                    items(end + 1) = createResult.Session.Paths.RootDirectory;
                    app.ProjectDirectoryDropDown.Items = items;
                end

                app.updateStatus("model", "init");

                for i = 1:numel(createResult.Messages)
                    app.notifyInfo(createResult.Messages(i));
                end

                artifacts = app.LegacyProjectInitializer.initialize( ...
                    createResult.Session);

                app.applyLegacyProjectArtifacts(artifacts);

                for i = 1:numel(artifacts.Messages)
                    app.notifyInfo(artifacts.Messages(i));
                end

                app.renderCreatedProjectArtifacts();
                app.refreshPresentation();

            catch ME
                app.updateStatus("model", "error");
                app.notifyException( ...
                    ME, ...
                    Title = "Project create failed", ...
                    Alert = true);
            end

        end

        % Value changed function: ProjectDirectoryDropDown
        function ProjectDirectoryDropDownValueChanged(app, event)

            value = string(app.ProjectDirectoryDropDown.Value);

            try
                value = app.resolveProjectOpenInput(value);
            catch ME
                msg = "Selected project path does not exist: " + ...
                    string(app.ProjectDirectoryDropDown.Value);

                LogTextDate(app, msg + newline + string(ME.message), "Error");
                return
            end

            app.ProjectDirectoryDropDown.Value = value;

            if ~any(strcmp(app.ProjectDirectoryDropDown.Items, value))
                app.ProjectDirectoryDropDown.Items{end + 1} = value;
            end

        end

        % Button pushed function: TemplateModelBrowseButton
        function TemplateModelBrowseButtonPushed(app, event)

            [templateModelDirectory, isOK] = app.uiGetDirWrap( ...
                Title = "Select Template Model Directory", ...
                StartPath = app.TemplateModelDirectoryDropDown.Value);

            if ~isOK
                return
            end

            % If the directory is equal to the current directory, do nothing
            if strcmp(app.TemplateModelDirectoryDropDown.Value, templateModelDirectory)
                return;
            end

            % Update the dropdown value
            app.TemplateModelDirectoryDropDown.Value = templateModelDirectory;

            % Add the directory to the item
            if ~any(strcmp(app.TemplateModelDirectoryDropDown.Items, templateModelDirectory))
                app.TemplateModelDirectoryDropDown.Items{end + 1} = templateModelDirectory;
            end

            app.refreshPresentation();
        end

        % Button pushed function: TemplateModelLoadButton
        function TemplateModelLoadButtonPushed(app, event)

            cleanupPresentation = app.beginPresentationOperation(); %#ok<NASGU>

            try
                app.updateStatus("model", "running");

                app.ensureTemplateModelLoadService();

                modelLocation = openmebius.domain.model.ModelLocation ...
                    .fromDirectory(string(app.TemplateModelDirectoryDropDown.Value));

                templateModelResult = ...
                    app.TemplateModelLoadService.load(modelLocation);

                app.applyTemplateModelLoadResult(templateModelResult);

                for i = 1:numel(templateModelResult.Messages)
                    app.notifyInfo(templateModelResult.Messages(i));
                end

                app.notifyInfo("Constructing EMU network...");

                pause(0.5)

                loadEMUModel(app)

                loadPathway(app)

                app.notifyInfo("EMU network was successfully constructed.");
                app.updateStatus("model", "finished");

            catch ME
                app.updateStatus("model", "error");
                app.notifyException( ...
                    ME, ...
                    Title = "Template model load failed", ...
                    Alert = true);
            end

        end

        % Button pushed function: TemplateModelSaveButton
        function TemplateModelSaveButtonPushed(app, event)

        end

        % Value changed function: TemplateModelDirectoryDropDown
        function TemplateModelDirectoryDropDownValueChanged(app, event)

            value = app.TemplateModelDirectoryDropDown.Value;

            % Check directory exists
            if ~isfolder(value)
                msg = "Selected directory does not exist: " + value;
                LogTextDate(app, msg, "Error");
                return
            end % if ~isfolder(value)

            % Add the directory to the item
            if ~any(strcmp(app.TemplateModelDirectoryDropDown.Items, value))
                app.TemplateModelDirectoryDropDown.Items{end + 1} = value;
            end % if exist

        end

        % Button pushed function: ModelReloadButton
        function ModelReloadButtonPushed(app, event)

            if strcmp(app.ModelEditButton.Enable, 'off')
                columnEditable = true(1, width(app.ModelTable.Data));
                loadModelTable(app, ColumnEditable = columnEditable);
            else
                loadModelTable(app);
            end

            loadPathway(app);

            app.LogTextDate("Model table reloaded", "Info");
        end

        % Button pushed function: ModelEditButton
        function ModelEditButtonPushed(app, event)

            import openmebius.presentation.main.EditTarget

            app.beginPresentationEdit(EditTarget.Model);

            app.LogTextDate("Model table is now editable", "Info");
        end

        % Button pushed function: ModelSaveButton
        function ModelSaveButtonPushed(app, event)

            app.beginPresentationEditCommit();

            try
                app.updateStatus("model", "running");

                tableIn = app.ModelTable.Data;
                validationReport = app.model.updateModelTableGUI(tableIn);

                resetModelTableColorFormat(app);

                if ~isempty(validationReport.InvalidRows)
                    addStyle( ...
                        app.ModelTable, ...
                        app.styleError, ...
                        'row', ...
                        validationReport.InvalidRows);
                end

                app.showModelValidationReport(validationReport);

                if ~validationReport.IsValid
                    app.updateStatus("model", "error");

                    app.finishPresentationEditCommit(false);
                    return
                end

                app.updateStatus("model", "finished");

                app.finishPresentationEditCommit(true);

            catch ME
                app.finishPresentationEditCommit(false);
                rethrow(ME)
            end

        end

        % Cell selection callback: ModelTable
        function ModelTableCellSelection(app, event)

            indices = event.Indices;

            if isempty(indices)
                return
            end

            row = indices(1, 1);

            highlight = false(size(app.ModelTable.Data, 1), 1);
            highlight(row) = true;

            drawFluxLabel( ...
                app.model, ...
                app.MainUIAxes, ...
                [], ...
                highlight = highlight, ...
                darkmode = isDarkTheme(app) ...
            );
        end

        % Menu selected function: AddLabelMenu
        function AddLabelMenuSelected(app, event)

            % 座標を取得
            point = get(app.MainUIAxes, 'CurrentPoint');
            x = point(1, 1);
            y = point(1, 2);

            % Get the label name
            data = app.ModelTable.Data;
            idx = app.ModelTable.Selection;

            if isempty(idx)
                msg = openmebius.infrastructure.logging.Logger ...
                    .formatDatedMessage( ...
                    "Please select a reaction to add a label.", ...
                "Warning");
                app.LogText(msg);
                return
            end

            dataSelected = data(idx(1, 1), :);
            dataSelected.x = x;
            dataSelected.y = y;
            data(idx(1, 1), :) = dataSelected;
            app.ModelTable.Data = data;
            dataXY = app.model.tableXY;
            dataXY(dataSelected.Properties.RowNames, :) = dataSelected(:, {'x', 'y'});
            app.model.tableXY = dataXY;

            updateModel(app)
            loadPathway(app)

            rxnName = dataSelected.Properties.RowNames{1};
            msg = openmebius.infrastructure.logging.Logger ...
                .formatDatedMessage( ...
                "Label position added to " + rxnName + ...
                " x: " + string(x) + " y: " + string(y), ...
            "Info");
            app.LogText(msg);
        end

        % Menu selected function: RemoveLabelMenu
        function RemoveLabelMenuSelected(app, event)

        end

        % Button pushed function: MSReloadButton
        function MSReloadButtonPushed(app, event)

            if strcmp(app.MSEditButton.Enable, 'off')
                columnEditable = true;
                loadMSTable(app, isColumnEditable = columnEditable);
            else
                loadMSTable(app);
            end

            msg = openmebius.infrastructure.logging.Logger ...
                .formatDatedMessage("MS table reloaded", "Info");
            app.LogText(msg);
        end

        % Button pushed function: MSEditButton
        function MSEditButtonPushed(app, event)

            import openmebius.presentation.main.EditTarget

            app.beginPresentationEdit(EditTarget.MassSpectrometry);

            app.LogTextDate("MS table is now editable", "Info");
        end

        % Button pushed function: MSSaveButton
        function MSSaveButtonPushed(app, event)

            app.beginPresentationEditCommit();

            try
                app.updateStatus("model", "running");

                tableMS = app.MSTable.Data;
                tableAtom = app.AtomTable.Data;

                msReport = app.model.updateMSTable(tableMS);
                resetMSTableColorFormat(app)

                if ~isempty(msReport.InvalidRows)
                    addStyle( ...
                        app.MSTable, ...
                        app.styleError, ...
                        'row', ...
                        msReport.InvalidRows);
                end

                atomReport = app.model.updateAtomTable(tableAtom);

                if ~isempty(atomReport.InvalidRows)
                    addStyle( ...
                        app.AtomTable, ...
                        app.styleError, ...
                        'row', ...
                        atomReport.InvalidRows);
                end

                app.showModelValidationReport(msReport);
                app.showModelValidationReport(atomReport);

                if ~msReport.IsValid || ~atomReport.IsValid
                    app.updateStatus("model", "error");

                    app.finishPresentationEditCommit(false);
                    return
                end

                app.updateStatus("model", "finished");

                app.LogTextDate("MS table saved", "Info");

                app.finishPresentationEditCommit(true);

            catch ME
                app.finishPresentationEditCommit(false);
                rethrow(ME)
            end

        end

        % Button pushed function: ExpCalculationButton
        function ExpCalculationButtonPushed(app, event)

            cleanupPresentation = app.beginPresentationOperation(); %#ok<NASGU>
            app.ensureExperimentCalculationController();
            app.ensureExperimentPresenter();
            app.renderExperimentCalculationViewModel( ...
                app.ExperimentPresenter.presentCalculationStarted());

            outcome = app.ExperimentCalculationController.calculate( ...
                app.model, ...
                app.exp, ...
                app.batch, ...
                app.ExpTable.Data, ...
                app.UptakeTable.Data, ...
                app.LabelTable.Data);
            app.renderExperimentCalculationViewModel( ...
                app.ExperimentPresenter ...
                .presentCalculationOutcome(outcome));

        end

        % Button pushed function: ExpImportButton
        function ExpImportButtonPushed(app, ~)

            % Wrap
            [files, isOK] = app.uiGetFileWrap( ...
                Filter = {'*.xlsx;*.xls', 'Excel Files (*.xlsx, *.xls)'}, ...
                Title = 'Select Experimental Data File', ...
                MultiSelect = "on" ...
            );

            if ~isOK || isempty(files)
                app.ensureExperimentPresenter();
                app.renderExperimentImportViewModel( ...
                    app.ExperimentPresenter ...
                    .presentFileImportCanceled());
                return
            end

            files = string(files(:));
            cleanupPresentation = app.beginPresentationOperation(); %#ok<NASGU>
            app.ensureExperimentImportController();
            app.ensureExperimentPresenter();
            app.renderExperimentImportViewModel( ...
                app.ExperimentPresenter.presentImportStarted());

            experimentLocation = ...
                openmebius.domain.experiment.ExperimentLocation ...
                .fromDirectory(app.directoryExp);
            outcome = app.ExperimentImportController.importFiles( ...
                experimentLocation, ...
                files, ...
                app.model);
            app.renderExperimentImportViewModel( ...
                app.ExperimentPresenter ...
                .presentFileImportOutcome(outcome));

        end

        % Button pushed function: ExpReloadButton
        function ExpReloadButtonPushed(app, ~)

            cleanupPresentation = app.beginPresentationOperation(); %#ok<NASGU>
            app.ensureExperimentImportController();
            app.ensureExperimentPresenter();
            app.renderExperimentImportViewModel( ...
                app.ExperimentPresenter.presentImportStarted());

            experimentLocation = ...
                openmebius.domain.experiment.ExperimentLocation ...
                .fromDirectory(app.directoryExp);
            outcome = app.ExperimentImportController.reload( ...
                experimentLocation, ...
                app.model);
            app.renderExperimentImportViewModel( ...
                app.ExperimentPresenter.presentReloadOutcome(outcome));

        end

        % Button pushed function: ExpSaveButton
        function ExpSaveButtonPushed(app, ~)

            cleanupPresentation = app.beginPresentationOperation(); %#ok<NASGU>
            app.ensureExperimentEditController();
            app.ensureExperimentPresenter();
            app.renderExperimentEditViewModel( ...
                app.ExperimentPresenter.presentEditStarted());

            outcome = app.ExperimentEditController.saveInfo( ...
                app.model, ...
                app.exp, ...
                app.batch, ...
                app.ExpTable.Data);
            app.renderExperimentEditViewModel( ...
                app.ExperimentPresenter ...
                .presentInfoSaveOutcome(outcome));

        end

        % Key press function: ExpTable
        function ExpTableKeyPress(app, event)

            key = event.Key;
            modifier = event.Modifier;
            isCtrlC = any(strcmp(modifier, 'control')) && strcmp(key, 'c');
            isCtrlV = any(strcmp(modifier, 'control')) && strcmp(key, 'v');

            if isCtrlC
                app.copyTableToClipboard(app.ExpTable);
            end

            if isCtrlV
                app.pasteClipboardToTable(app.ExpTable);
            end

        end

        % Menu selected function: ViewMStableMenu
        function ViewMStableMenuSelected(app, event)

            idx = app.ExpTable.Selection;

            if isempty(idx)
                msg = openmebius.infrastructure.logging.Logger ...
                    .formatDatedMessage( ...
                    "Please select an experiment to view MS data.", ...
                "Warning");
                app.LogText(msg);
                return
            end

            if size(idx, 1) > 1
                msg = openmebius.infrastructure.logging.Logger ...
                    .formatDatedMessage( ...
                    "Please select only one experiment to view MS data.", ...
                "Warning");
                app.LogText(msg);
                return
            end

            idxRow = idx(1, 1);

            cleanupPresentation = app.beginPresentationOperation();
            presenter = openmebius.presentation.experiment ...
                .MSViewPresenter(app.exp);

            if ~presenter.hasCalculatedMDV()
                app.notifyWarning( ...
                    "MDV-derived tables have not been calculated. " + ...
                    "Press Calculate MDV in the Experiment tab before " + ...
                    "viewing MDV, biomass-corrected MDV or enrichment data.");
            end

            app.MSViewApp = MSView( ...
                presenter, idxRow, app.isDarkTheme());
            app.MSViewListeners = addlistener( ...
                app.MSViewApp, ...
                'ComparisonRequested', ...
                @(~, ~) app.openMSComparison());
        end

        % Button pushed function: TracerConfigButton
        function LabelConfigButtonPushed(app, event)

            cleanupPresentation = app.beginPresentationOperation();
            app.LabelConfigApp = LabelConfig(app, app.model.tableLabelView, app.model.structLabelView);
        end

        % Button pushed function: TracerReloadButton
        function TracerReloadButtonPushed(app, event)

            app.loadTracerTable();
            msg = openmebius.infrastructure.logging.Logger ...
                .formatDatedMessage( ...
                "Tracer and uptake tables reloaded", ...
            "Info");
            app.LogText(msg);
        end

        % Button pushed function: TracerSaveButton
        function TracerSaveButtonPushed(app, ~)

            cleanupPresentation = app.beginPresentationOperation(); %#ok<NASGU>
            app.ensureExperimentEditController();
            app.ensureExperimentPresenter();
            app.renderExperimentEditViewModel( ...
                app.ExperimentPresenter.presentEditStarted());

            outcome = app.ExperimentEditController.saveTracer( ...
                app.model, ...
                app.exp, ...
                app.batch, ...
                app.UptakeTable.Data, ...
                app.LabelTable.Data);
            app.renderExperimentEditViewModel( ...
                app.ExperimentPresenter ...
                .presentTracerSaveOutcome(outcome));

        end

        % Double-clicked callback: LabelTable
        function LabelTableDoubleClicked(app, event)

            tableOriginal = app.exp.tableTracersInfo;
            tableNow = app.LabelTable.Data;

            if ~isequaln(tableOriginal, tableNow)
                msg = openmebius.infrastructure.logging.Logger ...
                    .formatDatedMessage( ...
                    "Label table has been modified. " + ...
                    "Please save the table before editing.", ...
                "Warning");
                app.LogText(msg);
                return
            end

            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;

            if isempty(displayRow) || isempty(displayColumn)
                return
            end

            cleanupPresentation = app.beginPresentationOperation();

            app.TracerConfigApp = ...
                TracerConfig( ...
                app, ...
                [displayRow, displayColumn] ...
            );
        end

        % Key press function: UptakeTable
        function UptakeTableKeyPress(app, event)

            key = event.Key;
            modifier = event.Modifier;

            isCtrlC = strcmp(key, 'c') && ismember('control', modifier);
            isCtrlV = strcmp(key, 'v') && ismember('control', modifier);

            if isCtrlC
                app.copyTableToClipboard(app.UptakeTable);
            end

            if isCtrlV
                app.pasteClipboardToTable(app.UptakeTable);
            end

        end

        % Button pushed function: RunAutoButton
        function RunAutoButtonPushed(app, event)

            app.batch.autoFillBatch();
            updateBatchTable(app);

            msg = openmebius.infrastructure.logging.Logger ...
                .formatDatedMessage( ...
                "Batch table has been automatically filled.", ...
            "Info");
            app.LogText(msg);
        end

        % Button pushed function: RunConfigButton
        function RunConfigButtonPushed(app, event)

            selection = app.RunTable.Selection;

            if isempty(selection)
                msg = openmebius.infrastructure.logging.Logger ...
                    .formatDatedMessage( ...
                    "Please select a batch to configure.", ...
                "Warning");
                app.LogText(msg);
                return
            end

            updateBatchTable(app);

            cleanupPresentation = app.beginPresentationOperation();

            app.RunConfigApp = RunConfig(app, selection);
        end

        % Button pushed function: RunReloadButton
        function RunReloadButtonPushed(app, event)

            updateBatchTable(app);
            msg = openmebius.infrastructure.logging.Logger ...
                .formatDatedMessage("Batch table reloaded", "Info");
            app.LogText(msg);
        end

        % Button pushed function: RunSaveButton
        function RunSaveButtonPushed(app, event)

            app.batch.updateBatchFromGUI(app.RunTable.Data);
            updateBatchTable(app);

            app.batch.saveBatchFile()

            msg = openmebius.infrastructure.logging.Logger ...
                .formatDatedMessage( ...
                "Batch table has been saved.", ...
            "Info");
            app.LogText(msg);
        end

        % Button pushed function: RunRunButton
        function RunRunButtonPushed(app, event)

            app.ensureBatchPresenter();
            app.ensureBatchRunController();

            if app.Presenter.isRunning()

                app.requestPresentationCancelRun();

                app.renderBatchRunViewModel( ...
                    app.BatchPresenter.presentCancelRequested());
                app.BatchRunController.cancel(app.batch);

                return
            end

            app.beginPresentationRun();
            cleanupPresentation = onCleanup( ...
                @() app.finishPresentationRunSafely());

            app.renderBatchRunViewModel( ...
                app.BatchPresenter.presentRunStarted());

            updateBatchTable(app);

            % updateBatchTable may reset RunTable.ColumnEditable.
            app.refreshPresentation();

            outcome = app.BatchRunController.run( ...
                app.batch, app.directoryResult);
            app.renderBatchRunViewModel( ...
                app.BatchPresenter.presentRunOutcome(outcome));
            outcome.rethrowFailure();

        end

        % Menu selected function: AddbatchMenu
        function RunAddbatchMenuSelected(app, event)

            app.RunAddBatchApp = RunAddBatch(app, 'parallel');
        end

        % Menu selected function: RemovethisbatchMenu
        function RunRemovethisbatchMenuSelected(app, event)

            selectedRows = app.RunTable.Selection;

            if isempty(selectedRows)
                msg = "Please select a batch to remove.";
                LogTextDate(app, msg, "Warning");
                return
            end % if

            selectedRows = selectedRows(:, 1); % Get the first column (row indices)
            selectedRows = unique(selectedRows); % Ensure unique selection
            batchIDs = app.RunTable.Data.ID(selectedRows);

            [answer, isOK] = app.uiConfirmWrap( ...
                "Are you sure you want to remove the selected batch?", ...
                "Remove Batch", ...
                Options = ["Yes", "No"], ...
                DefaultOption = "No", ...
                CancelOption = "No", ...
                Icon = "warning");

            if isOK && answer == "Yes"

                for i = 1:length(batchIDs)
                    batchID = batchIDs(i);
                    app.batch.removeBatch(batchID);
                end % for i

                updateBatchTable(app);

                msg = "Selected batch has been removed.";
                LogTextDate(app, msg, "Info");

            end % if

        end

        % Menu selected function: ParallellabelingMenu
        function RunParallellabelingMenuSelected(app, event)

        end

        % Key press function: RunTable
        function RunKeyPress(app, event)

            key = event.Key;

            % If delete key is pressed
            isDelete = strcmp(key, 'delete');

            if isDelete

                % Get selected rows
                selectedRows = app.RunTable.Selection;

                if isempty(selectedRows)
                    return
                end % if

                selectedRows = selectedRows(:, 1); % Get the first column (row indices)
                selectedRows = unique(selectedRows); % Ensure unique selection
                batchIDs = app.RunTable.Data.ID(selectedRows);

                % Confirm deletion
                answer = uiconfirm(app.OpenMebius2UIFigure, ...
                    "Are you sure you want to delete the selected batch?", ...
                    "Delete Batch", ...
                    'Options', {'Yes', 'No'}, ...
                    'DefaultOption', 'No', ...
                    'CancelOption', 'No');

                if strcmp(answer, 'Yes')

                    for i = 1:length(batchIDs)
                        batchID = batchIDs(i);
                        app.batch.removeBatch(batchID);
                    end % for i

                    updateBatchTable(app);

                end % if

            end % if isDelete

        end

        % Value changed function: ResultDropDown
        function ResultDropDownValueChanged(app, event)

            loadMainResultTable(app);
            updateResultPlot(app);
        end

        % Cell selection callback: ResultSubTable
        function ResultSubTableCellSelection(app, event)

            loadMainResultTable(app);
            updateResultPlot(app);
        end

        % Cell edit callback: ResultSubTable
        function ResultSubTableCellEdit(app, event)

        end

        % Cell selection callback: ResultMainTable
        function ResultMainTableCellSelection(app, event)

            updateResultPlot(app);
        end

        % Button pushed function: ResultReportButton
        function ResultReportButtonPushed(app, event)

            try
                app.ensureReportGenerationService();

                resultLocation = openmebius.domain.result.ResultLocation ...
                    .fromDirectory(app.directoryResult);

                reportGenerationResult = ...
                    app.ReportGenerationService.generate( ...
                    resultLocation, ...
                    app.model, ...
                    app.exp, ...
                    app.result, ...
                    IsDeployed = isdeployed);

                app.applyReportGenerationResult(reportGenerationResult);

                for i = 1:numel(reportGenerationResult.Messages)
                    app.notifyInfo(reportGenerationResult.Messages(i));
                end

            catch ME

                switch string(ME.identifier)

                    case "OpenMebius2:Report:UnavailableInDeployed"
                        app.notifyWarning(string(ME.message));

                    case "OpenMebius2:Report:DataUnavailable"
                        app.notifyError(string(ME.message));

                    otherwise
                        app.notifyException( ...
                            ME, ...
                            Title = "Report generation failed", ...
                            Alert = true);
                end

            end

        end

        % Button pushed function: ResultReloadButton
        function ResultReloadButtonPushed(app, event)

            app.updateResult();
            msg = openmebius.infrastructure.logging.Logger ...
                .formatDatedMessage("Result data reloaded", "Info");
            app.LogText(msg);
        end

        % Button pushed function: ResultSaveButton
        function ResultSaveButtonPushed(app, event)

            try
                app.ensureResultExportService();

                [folder, isOK] = app.uiGetDirWrap( ...
                    StartPath = app.directoryResult, ...
                    Title = "Select Directory to Save Result Files" ...
                );

                if ~isOK
                    return; % User canceled the dialog
                end

                selectedRows = app.selectedTableRows(app.ResultSubTable);

                if isempty(selectedRows)
                    app.notifyWarning("Please select a result to save.");
                    return
                end % if

                batchIDs = app.ResultSubTable.Data.ID;
                selectedBatchIDs = string(batchIDs(selectedRows));
                selectedBatchIDs = selectedBatchIDs(:);
                batchNames = app.ResultSubTable.Data.Name;
                selectedBatchNames = string(batchNames(selectedRows));
                selectedBatchNames = selectedBatchNames(:);
                outputLocation = openmebius.domain.result.ResultLocation ...
                    .fromDirectory(folder);

                resultExportResult = app.ResultExportService.export( ...
                    app.result, ...
                    selectedBatchIDs, ...
                    selectedBatchNames, ...
                    outputLocation);

                for i = 1:numel(resultExportResult.Messages)
                    app.notifyInfo(resultExportResult.Messages(i));
                end

            catch ME

                switch string(ME.identifier)

                        case { ...
                                  "OpenMebius2:ResultExport:ResultUnavailable", ...
                                  "OpenMebius2:ResultExport:EmptySelection", ...
                                  "OpenMebius2:ResultExport:SelectionMismatch", ...
                                  "OpenMebius2:ResultExport:OutputDirectoryUnavailable", ...
                                  "OpenMebius2:ResultExport:OutputDirectoryNotFound", ...
                                  "OpenMebius2:ResultExport:OutputDirectoryExists", ...
                                  "OpenMebius2:ResultExport:CreateDirectoryFailed" ...
                              }
                        app.notifyError(string(ME.message));

                    otherwise
                        app.notifyException( ...
                            ME, ...
                            Title = "Result export failed", ...
                            Alert = true);
                end

            end

        end

        % Menu selected function: ReloadWindowMenu
        function ReloadWindowMenuSelected(app, event)

            loadHistory(app)
            initLog(app)
            cleanupPresentation = app.beginPresentationOperation();

            % Reset all components
            resetAllComponents(app);

            % Reset status
            updateStatus(app, "model", "init");
            updateStatus(app, "experiment", "init");
            updateStatus(app, "batch", "init");
            updateStatus(app, "result", "init");
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)

            app.ensureSlackNotifier();

            try

                if ~isempty(app.PreferencesApp) && isvalid(app.PreferencesApp)
                    figure(app.PreferencesApp.PreferencesUIFigure);
                    return
                end

            catch
                app.PreferencesApp = [];
            end

            try
                app.beginPresentationPreferences();

                app.PreferencesApp = Preferences(app.SlackNotifier);

                app.attachPreferencesListeners(app.PreferencesApp);

            catch ME
                app.finishPresentationPreferences();
                app.notifyException( ...
                    ME, ...
                    Title = "Preferences failed", ...
                    Alert = true);
            end

        end

        % Menu selected function: RelativetoMenu
        function RelativetoMenuSelected(app, event)

            RowNames = app.ResultMainTable.RowName;
            selectedFlux = app.ResultMainTable.Selection;

            if isempty(selectedFlux)
                msg = "Please select a flux to set relative values.";
                LogTextDate(app, msg, "Warning");
                return
            end

            if isempty(RowNames)
                return
            end

            selectedFluxIdx = RowNames{selectedFlux(1)};

            loadMainResultTable(app, relative = true, relativeTo = selectedFluxIdx);
        end

        % Menu selected function: RangeplotMenu
        function RangeplotMenuSelected(app, event)

            % Selected rows
            selectedRows = app.ResultSubTable.Selection;

            disp(selectedRows)

            if isempty(selectedRows)
                msg = "Please select at least one flux to plot range plot.";
                LogTextDate(app, msg, "Warning");
                return
            end

        end

        % Menu selected function: ViewsuggestionMenu
        function ViewsuggestionMenuSelected(app, event)

            % Selected rows
            selectedRows = app.ResultSubTable.Selection;

            if isempty(selectedRows) || size(selectedRows, 1) > 1
                msg = "Please select one flux to view suggestions.";
                LogTextDate(app, msg, "Warning");
                return
            end

            [isExist, suggestion] = getNextLabelSuggestion( ...
                app.result, ...
                app.ResultSubTable.Data.ID{selectedRows(1)} ...
            );

            if ~isExist
                msg = "No labeling suggestion available for the selected flux.";
                LogTextDate(app, msg, "Warning");
                return
            end

            suggestion.sampleName = app.ResultSubTable.Data.Name{selectedRows(1)};
            suggestion.batchID = app.ResultSubTable.Data.ID{selectedRows(1)};

            app.ViewSuggestionApp = ViewSuggestion(suggestion);
        end

        % Menu selected function: CopythistracerforallentriesMenu
        function CopythistracerforallentriesMenuSelected(app, ~)

            currentData = app.LabelTable.Data;
            selected = app.LabelTable.Selection;
            app.ensureExperimentPresenter();

            if isempty(selected)
                app.renderExperimentEditViewModel( ...
                    app.ExperimentPresenter ...
                    .presentTracerCopySelectionRequired());
                return
            end

            app.ensureExperimentEditController();
            outcome = app.ExperimentEditController ...
                .copyTracerToAllEntries( ...
                app.model, ...
                app.exp, ...
                app.batch, ...
                currentData, ...
                selected);
            app.renderExperimentEditViewModel( ...
                app.ExperimentPresenter ...
                .presentTracerCopyOutcome(outcome));

        end

        % Menu selected function: ImportMSdatafromtextfilesMenu
        function ImportMSdatafromtextfilesMenuSelected(app, ~)

            [importDirectory, isOK] = app.uiGetDirWrap( ...
                Title = "Select Directory Containing MS Data Text Files", ...
                StartPath = app.directoryExp);

            if ~isOK
                return
            end

            cleanupPresentation = app.beginPresentationOperation(); %#ok<NASGU>
            app.ensureExperimentImportController();
            app.ensureExperimentPresenter();
            app.renderExperimentImportViewModel( ...
                app.ExperimentPresenter.presentImportStarted());

            experimentLocation = ...
                openmebius.domain.experiment.ExperimentLocation ...
                .fromDirectory(app.directoryExp);
            outcome = app.ExperimentImportController ...
                .importShimadzuASCII( ...
                    importDirectory, ...
                    experimentLocation, ...
                    app.model);
            app.renderExperimentImportViewModel( ...
                app.ExperimentPresenter ...
                .presentRawMSImportOutcome(outcome));

        end

        % Menu selected function: OpenMebius2manualMenu
        function OpenMebius2manualMenuSelected(app, event)

            try
                msg = "Opening the OpenMebius2 manual in your web browser.";
                app.LogTextDate(msg, "Info");
                web('https://github.com/metabolic-engineering/OpenMebius2', '-browser');
            catch
                msg = "Unable to open the OpenMebius2 manual. Please check your internet connection.";
                app.LogTextDate(msg, "Error");
            end

        end

        % Menu selected function: AboutOpenMebius2Menu
        function AboutOpenMebius2MenuSelected(app, event)

            msg = "This is a test message";

            app.showNotification( ...
                openmebius.presentation.notification.Notification.info( ...
                msg, ...
                Title = "About OpenMebius2", ...
                ShowAlert = true));
        end

        % Menu selected function: ClearcacheMenu
        function ClearcacheMenuSelected(app, event)

            % Clear cache directory
            app.clearHistory();
        end

        % Menu selected function: ExporttemplateExcelfileMenu
        function ExporttemplateExcelfileMenuSelected(app, event)

            if isempty(app.model) || ~isvalid(app.model)
                msg = "Model is not loaded. Please load a model before exporting template Excel file.";
                LogTextDate(app, msg, "Error");
                return
            end

            cellData = getTemplateMSTable(app.model);

            msg = "Exporting template Excel file. Please select the location to save the file.";
            app.LogTextDate(msg, "Info");

            [file, isOK] = app.uiGetFileWrap( ...
                Filter = {'*.xlsx', 'Excel Files (*.xlsx)'}, ...
                Title = 'Save Template Excel File', ...
                MultiSelect = "off", ...
                DefaultName = "Template_MS_Table.xlsx", ...
                Save = true ...
            );

            if ~isOK
                return; % User canceled the dialog
            end

            % Write cell data to Excel file
            try
                writematrix(cellData, file, 'Sheet', 'MS');
                msg = "Template Excel file exported successfully: " + file;
                app.LogTextDate(msg, "Info");
            catch ME
                msg = "Failed to export template Excel file. Error: " + ME.message;
                app.LogTextDate(msg, "Error");
            end

        end

        % Menu selected function: ViewlogsMenu
        function ViewlogsMenuSelected(app, event)

            app.LogApp = AppLogs();
        end

        % Key press function: OpenMebius2UIFigure
        function OpenMebius2UIFigureKeyPress(app, event)

            modifier = event.Modifier;
            key = event.Key;

            % F5
            isF5 = strcmp(key, 'f5') && isempty(modifier);

            if isF5

                % TabGroup
                currentTab = app.TabGroup.SelectedTab.Title;

                switch currentTab
                    case "Stoichiometry"
                        app.ModelReloadButtonPushed();
                    case "MS"
                        app.MSReloadButtonPushed();
                    case "Experiment"
                        app.ExpReloadButtonPushed();
                    case "Tracer"
                        app.TracerReloadButtonPushed();
                    case "Batch"
                        app.RunReloadButtonPushed();
                    case "Result"
                        app.ResultReloadButtonPushed();
                end % switch

            end % if isF5

        end

    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OpenMebius2UIFigure = uifigure('Visible', 'off');
            app.OpenMebius2UIFigure.Name = 'OpenMebius2';
            app.OpenMebius2UIFigure.Icon = fullfile(pathToMLAPP, '+img', 'logo.png');
            app.OpenMebius2UIFigure.CloseRequestFcn = createCallbackFcn(app, @OpenMebius2UIFigureCloseRequest, true);
            app.OpenMebius2UIFigure.KeyPressFcn = createCallbackFcn(app, @OpenMebius2UIFigureKeyPress, true);
            app.ApplicationMenu = uimenu(app.OpenMebius2UIFigure);
            app.ApplicationMenu.Text = 'Application';
            app.ReloadWindowMenu = uimenu(app.ApplicationMenu);
            app.ReloadWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloadWindowMenuSelected, true);
            app.ReloadWindowMenu.Text = 'Reload Window';
            app.ClearcacheMenu = uimenu(app.ApplicationMenu);
            app.ClearcacheMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearcacheMenuSelected, true);
            app.ClearcacheMenu.Text = 'Clear cache';
            app.PreferencesMenu = uimenu(app.ApplicationMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.ExperimentaldataMenu = uimenu(app.OpenMebius2UIFigure);
            app.ExperimentaldataMenu.Text = 'Experimental data';
            app.ExporttemplateExcelfileMenu = uimenu(app.ExperimentaldataMenu);
            app.ExporttemplateExcelfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttemplateExcelfileMenuSelected, true);
            app.ExporttemplateExcelfileMenu.Text = 'Export template Excel file';
            app.FilesMenu = uimenu(app.OpenMebius2UIFigure);
            app.FilesMenu.Text = 'Files';
            app.ImportMSdatafromtextfilesMenu = uimenu(app.FilesMenu);
            app.ImportMSdatafromtextfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMSdatafromtextfilesMenuSelected, true);
            app.ImportMSdatafromtextfilesMenu.Text = 'Import MS data from text files';
            app.ModelMenu = uimenu(app.OpenMebius2UIFigure);
            app.ModelMenu.Text = 'Model';
            app.BatchMenu = uimenu(app.OpenMebius2UIFigure);
            app.BatchMenu.Text = 'Batch';
            app.ViewMenu = uimenu(app.OpenMebius2UIFigure);
            app.ViewMenu.Text = 'View';
            app.ViewReportMenu = uimenu(app.ViewMenu);
            app.ViewReportMenu.Text = 'View Report';
            app.HelpMenu = uimenu(app.OpenMebius2UIFigure);
            app.HelpMenu.Text = 'Help';
            app.ViewlogsMenu = uimenu(app.HelpMenu);
            app.ViewlogsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewlogsMenuSelected, true);
            app.ViewlogsMenu.Text = 'View logs';
            app.OpenMebius2manualMenu = uimenu(app.HelpMenu);
            app.OpenMebius2manualMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMebius2manualMenuSelected, true);
            app.OpenMebius2manualMenu.Text = 'OpenMebius2 manual';
            app.AboutOpenMebius2Menu = uimenu(app.HelpMenu);
            app.AboutOpenMebius2Menu.MenuSelectedFcn = createCallbackFcn(app, @AboutOpenMebius2MenuSelected, true);
            app.AboutOpenMebius2Menu.Text = 'About OpenMebius2';
            app.GridLayout = uigridlayout(app.OpenMebius2UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ProjectPanel = uipanel(app.GridLayout2);
            app.ProjectPanel.Title = 'Project';
            app.GridLayout3 = uigridlayout(app.ProjectPanel);
            app.ProjectLabel = uilabel(app.GridLayout3);
            app.ProjectLabel.Text = 'Project directory';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.ProjectnameEditFieldLabel = uilabel(app.GridLayout4);
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.ProjectNameEditField = uieditfield(app.GridLayout4, 'text');
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.ProjectLoadButton = uibutton(app.GridLayout5, 'push');
            app.ProjectLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectLoadButtonPushed, true);
            app.ProjectLoadButton.Text = 'Load';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.ProjectauthorEditFieldLabel = uilabel(app.GridLayout7);
            app.ProjectauthorEditFieldLabel.Text = 'Project author';
            app.ProjectAuthorEditField = uieditfield(app.GridLayout7, 'text');
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.OrganismEditFieldLabel = uilabel(app.GridLayout8);
            app.OrganismEditFieldLabel.Text = 'Organism';
            app.OrganismEditField = uieditfield(app.GridLayout8, 'text');
            app.GridLayout5_2 = uigridlayout(app.GridLayout3);
            app.ProjectSaveButton = uibutton(app.GridLayout5_2, 'push');
            app.ProjectSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectSaveButtonPushed, true);
            app.ProjectSaveButton.Text = 'Save';
            app.ProjectLabel_2 = uilabel(app.GridLayout3);
            app.ProjectLabel_2.Text = 'Template model file';
            app.GridLayout5_3 = uigridlayout(app.GridLayout3);
            app.TemplateModelLoadButton = uibutton(app.GridLayout5_3, 'push');
            app.TemplateModelLoadButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateModelLoadButtonPushed, true);
            app.TemplateModelLoadButton.Text = 'Load';
            app.TemplateModelSaveButton = uibutton(app.GridLayout3, 'push');
            app.TemplateModelSaveButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateModelSaveButtonPushed, true);
            app.TemplateModelSaveButton.Enable = 'off';
            app.TemplateModelSaveButton.Text = 'Save model as template';
            app.ProjectCreateButton = uibutton(app.GridLayout3, 'push');
            app.ProjectCreateButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectCreateButtonPushed, true);
            app.ProjectCreateButton.Enable = 'off';
            app.ProjectCreateButton.Text = 'Create project from template model';
            app.GridLayout16 = uigridlayout(app.GridLayout3);
            app.ProjectDirectoryDropDown = uidropdown(app.GridLayout16);
            app.ProjectDirectoryDropDown.Items = {};
            app.ProjectDirectoryDropDown.Editable = 'on';
            app.ProjectDirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectDirectoryDropDownValueChanged, true);
            app.ProjectDirectoryDropDown.Value = {};
            app.ProjectBrowseButton = uibutton(app.GridLayout16, 'push');
            app.ProjectBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectBrowseButtonPushed, true);
            app.ProjectBrowseButton.Text = 'Browse';
            app.GridLayout16_2 = uigridlayout(app.GridLayout3);
            app.TemplateModelDirectoryDropDown = uidropdown(app.GridLayout16_2);
            app.TemplateModelDirectoryDropDown.Items = {};
            app.TemplateModelDirectoryDropDown.Editable = 'on';
            app.TemplateModelDirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @TemplateModelDirectoryDropDownValueChanged, true);
            app.TemplateModelDirectoryDropDown.Value = {};
            app.TemplateModelBrowseButton = uibutton(app.GridLayout16_2, 'push');
            app.TemplateModelBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateModelBrowseButtonPushed, true);
            app.TemplateModelBrowseButton.Text = 'Browse';
            app.StatusHTML = uihtml(app.GridLayout2);
            app.GridLayout9 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout9);
            app.StoichiometryTab = uitab(app.TabGroup);
            app.StoichiometryTab.Title = 'Stoichiometry';
            app.GridLayout11 = uigridlayout(app.StoichiometryTab);
            app.ModelTable = uitable(app.GridLayout11);
            app.ModelTable.ColumnName = '';
            app.ModelTable.RowName = {};
            app.ModelTable.CellSelectionCallback = createCallbackFcn(app, @ModelTableCellSelection, true);
            app.ModelTable.Multiselect = 'off';
            app.ModelTable.Enable = 'off';
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.ModelSaveButton = uibutton(app.GridLayout12, 'push');
            app.ModelSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ModelSaveButtonPushed, true);
            app.ModelSaveButton.Enable = 'off';
            app.ModelSaveButton.Text = 'Save';
            app.ModelEditButton = uibutton(app.GridLayout12, 'push');
            app.ModelEditButton.ButtonPushedFcn = createCallbackFcn(app, @ModelEditButtonPushed, true);
            app.ModelEditButton.Enable = 'off';
            app.ModelEditButton.Text = 'Edit';
            app.ModelReloadButton = uibutton(app.GridLayout12, 'push');
            app.ModelReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ModelReloadButtonPushed, true);
            app.ModelReloadButton.Enable = 'off';
            app.ModelReloadButton.Text = 'Reload';
            app.MSTab = uitab(app.TabGroup);
            app.MSTab.Title = 'MS';
            app.GridLayout11_2 = uigridlayout(app.MSTab);
            app.GridLayout12_2 = uigridlayout(app.GridLayout11_2);
            app.MSSaveButton = uibutton(app.GridLayout12_2, 'push');
            app.MSSaveButton.ButtonPushedFcn = createCallbackFcn(app, @MSSaveButtonPushed, true);
            app.MSSaveButton.Enable = 'off';
            app.MSSaveButton.Text = 'Save';
            app.MSEditButton = uibutton(app.GridLayout12_2, 'push');
            app.MSEditButton.ButtonPushedFcn = createCallbackFcn(app, @MSEditButtonPushed, true);
            app.MSEditButton.Enable = 'off';
            app.MSEditButton.Text = 'Edit';
            app.MSReloadButton = uibutton(app.GridLayout12_2, 'push');
            app.MSReloadButton.ButtonPushedFcn = createCallbackFcn(app, @MSReloadButtonPushed, true);
            app.MSReloadButton.Enable = 'off';
            app.MSReloadButton.Text = 'Reload';
            app.GridLayout13 = uigridlayout(app.GridLayout11_2);
            app.MSTable = uitable(app.GridLayout13);
            app.MSTable.ColumnName = '';
            app.MSTable.RowName = {};
            app.MSTable.Multiselect = 'off';
            app.MSTable.Enable = 'off';
            app.AtomTable = uitable(app.GridLayout13);
            app.AtomTable.ColumnName = '';
            app.AtomTable.RowName = {};
            app.AtomTable.Multiselect = 'off';
            app.AtomTable.Enable = 'off';
            app.ExperimentTab = uitab(app.TabGroup);
            app.ExperimentTab.Title = 'Experiment';
            app.GridLayout11_3 = uigridlayout(app.ExperimentTab);
            app.GridLayout12_3 = uigridlayout(app.GridLayout11_3);
            app.ExpSaveButton = uibutton(app.GridLayout12_3, 'push');
            app.ExpSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ExpSaveButtonPushed, true);
            app.ExpSaveButton.Enable = 'off';
            app.ExpSaveButton.Text = 'Save';
            app.ExpReloadButton = uibutton(app.GridLayout12_3, 'push');
            app.ExpReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ExpReloadButtonPushed, true);
            app.ExpReloadButton.Enable = 'off';
            app.ExpReloadButton.Text = 'Reload';
            app.ExpImportButton = uibutton(app.GridLayout12_3, 'push');
            app.ExpImportButton.ButtonPushedFcn = createCallbackFcn(app, @ExpImportButtonPushed, true);
            app.ExpImportButton.Enable = 'off';
            app.ExpImportButton.Text = 'Import data';
            app.ExpCalculationButton = uibutton(app.GridLayout12_3, 'push');
            app.ExpCalculationButton.ButtonPushedFcn = createCallbackFcn(app, @ExpCalculationButtonPushed, true);
            app.ExpCalculationButton.Enable = 'off';
            app.ExpCalculationButton.Text = 'Calculate';
            app.GridLayout13_2 = uigridlayout(app.GridLayout11_3);
            app.GridLayout14 = uigridlayout(app.GridLayout13_2);
            app.BiomassTable = uitable(app.GridLayout14);
            app.BiomassTable.ColumnName = '';
            app.BiomassTable.RowName = {};
            app.BiomassTable.Enable = 'off';
            app.ExpTable = uitable(app.GridLayout13_2);
            app.ExpTable.ColumnName = '';
            app.ExpTable.RowName = {};
            app.ExpTable.Enable = 'off';
            app.ExpTable.KeyPressFcn = createCallbackFcn(app, @ExpTableKeyPress, true);
            app.TracerTab = uitab(app.TabGroup);
            app.TracerTab.Title = 'Tracer';
            app.GridLayout11_4 = uigridlayout(app.TracerTab);
            app.GridLayout12_4 = uigridlayout(app.GridLayout11_4);
            app.TracerSaveButton = uibutton(app.GridLayout12_4, 'push');
            app.TracerSaveButton.ButtonPushedFcn = createCallbackFcn(app, @TracerSaveButtonPushed, true);
            app.TracerSaveButton.Enable = 'off';
            app.TracerSaveButton.Text = 'Save';
            app.TracerReloadButton = uibutton(app.GridLayout12_4, 'push');
            app.TracerReloadButton.ButtonPushedFcn = createCallbackFcn(app, @TracerReloadButtonPushed, true);
            app.TracerReloadButton.Enable = 'off';
            app.TracerReloadButton.Text = 'Reload';
            app.TracerConfigButton = uibutton(app.GridLayout12_4, 'push');
            app.TracerConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LabelConfigButtonPushed, true);
            app.TracerConfigButton.Enable = 'off';
            app.TracerConfigButton.Text = 'Config';
            app.GridLayout15 = uigridlayout(app.GridLayout11_4);
            app.UptakeTable = uitable(app.GridLayout15);
            app.UptakeTable.ColumnName = '';
            app.UptakeTable.RowName = {};
            app.UptakeTable.Enable = 'off';
            app.UptakeTable.KeyPressFcn = createCallbackFcn(app, @UptakeTableKeyPress, true);
            app.LabelTable = uitable(app.GridLayout15);
            app.LabelTable.ColumnName = '';
            app.LabelTable.RowName = {};
            app.LabelTable.DoubleClickedFcn = createCallbackFcn(app, @LabelTableDoubleClicked, true);
            app.LabelTable.Enable = 'off';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.GridLayout11_5 = uigridlayout(app.RunTab);
            app.RunTable = uitable(app.GridLayout11_5);
            app.RunTable.ColumnName = '';
            app.RunTable.RowName = {};
            app.RunTable.Enable = 'off';
            app.RunTable.KeyPressFcn = createCallbackFcn(app, @RunKeyPress, true);
            app.GridLayout12_5 = uigridlayout(app.GridLayout11_5);
            app.RunRunButton = uibutton(app.GridLayout12_5, 'push');
            app.RunRunButton.ButtonPushedFcn = createCallbackFcn(app, @RunRunButtonPushed, true);
            app.RunRunButton.Enable = 'off';
            app.RunRunButton.Text = 'Run';
            app.RunSaveButton = uibutton(app.GridLayout12_5, 'push');
            app.RunSaveButton.ButtonPushedFcn = createCallbackFcn(app, @RunSaveButtonPushed, true);
            app.RunSaveButton.Enable = 'off';
            app.RunSaveButton.Text = 'Save';
            app.RunReloadButton = uibutton(app.GridLayout12_5, 'push');
            app.RunReloadButton.ButtonPushedFcn = createCallbackFcn(app, @RunReloadButtonPushed, true);
            app.RunReloadButton.Enable = 'off';
            app.RunReloadButton.Text = 'Reload';
            app.RunConfigButton = uibutton(app.GridLayout12_5, 'push');
            app.RunConfigButton.ButtonPushedFcn = createCallbackFcn(app, @RunConfigButtonPushed, true);
            app.RunConfigButton.Enable = 'off';
            app.RunConfigButton.Text = 'Config';
            app.RunAutoButton = uibutton(app.GridLayout12_5, 'push');
            app.RunAutoButton.ButtonPushedFcn = createCallbackFcn(app, @RunAutoButtonPushed, true);
            app.RunAutoButton.Enable = 'off';
            app.RunAutoButton.Text = 'Auto';
            app.ResultTab = uitab(app.TabGroup);
            app.ResultTab.Title = 'Result';
            app.GridLayout11_6 = uigridlayout(app.ResultTab);
            app.GridLayout12_6 = uigridlayout(app.GridLayout11_6);
            app.ResultSaveButton = uibutton(app.GridLayout12_6, 'push');
            app.ResultSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ResultSaveButtonPushed, true);
            app.ResultSaveButton.Enable = 'off';
            app.ResultSaveButton.Text = 'Save';
            app.ResultReloadButton = uibutton(app.GridLayout12_6, 'push');
            app.ResultReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ResultReloadButtonPushed, true);
            app.ResultReloadButton.Enable = 'off';
            app.ResultReloadButton.Text = 'Reload';
            app.ResultReportButton = uibutton(app.GridLayout12_6, 'push');
            app.ResultReportButton.ButtonPushedFcn = createCallbackFcn(app, @ResultReportButtonPushed, true);
            app.ResultReportButton.Enable = 'off';
            app.ResultReportButton.Text = 'Report';
            app.GridLayout12_7 = uigridlayout(app.GridLayout11_6);
            app.ResultDropDown = uidropdown(app.GridLayout12_7);
            app.ResultDropDown.Items = {'Overview', 'Details', 'Comparison'};
            app.ResultDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultDropDownValueChanged, true);
            app.ResultDropDown.Enable = 'off';
            app.ResultDropDown.Value = 'Overview';
            app.GridLayout15_2 = uigridlayout(app.GridLayout11_6);
            app.ResultMainTable = uitable(app.GridLayout15_2);
            app.ResultMainTable.ColumnName = '';
            app.ResultMainTable.RowName = {};
            app.ResultMainTable.SelectionType = 'row';
            app.ResultMainTable.CellSelectionCallback = createCallbackFcn(app, @ResultMainTableCellSelection, true);
            app.ResultMainTable.Enable = 'off';
            app.ResultSubTable = uitable(app.GridLayout15_2);
            app.ResultSubTable.ColumnName = '';
            app.ResultSubTable.RowName = {};
            app.ResultSubTable.SelectionType = 'row';
            app.ResultSubTable.CellEditCallback = createCallbackFcn(app, @ResultSubTableCellEdit, true);
            app.ResultSubTable.CellSelectionCallback = createCallbackFcn(app, @ResultSubTableCellSelection, true);
            app.ResultSubTable.Enable = 'off';
            app.LogTextArea = uitextarea(app.GridLayout9);
            app.LogTextArea.Editable = 'off';
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.MainUIAxes = uiaxes(app.GridLayout10);
            title(app.MainUIAxes, 'Title')
            xlabel(app.MainUIAxes, 'X')
            ylabel(app.MainUIAxes, 'Y')
            zlabel(app.MainUIAxes, 'Z')
            app.MainUIAxes.Visible = 'off';
            app.SubUIAxes = uiaxes(app.GridLayout10);
            title(app.SubUIAxes, 'Title')
            xlabel(app.SubUIAxes, 'X')
            ylabel(app.SubUIAxes, 'Y')
            zlabel(app.SubUIAxes, 'Z')
            app.SubUIAxes.Visible = 'off';
            app.ContextMenu = uicontextmenu(app.OpenMebius2UIFigure);
            app.AddLabelMenu = uimenu(app.ContextMenu);
            app.AddLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @AddLabelMenuSelected, true);
            app.AddLabelMenu.Text = 'Add Label';
            app.RemoveLabelMenu = uimenu(app.ContextMenu);
            app.RemoveLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveLabelMenuSelected, true);
            app.RemoveLabelMenu.Text = 'Remove Label';
            % Assign app.ContextMenu
            app.MainUIAxes.ContextMenu = app.ContextMenu;
            app.ExperimentContextMenu = uicontextmenu(app.OpenMebius2UIFigure);
            app.ViewMStableMenu = uimenu(app.ExperimentContextMenu);
            app.ViewMStableMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMStableMenuSelected, true);
            app.ViewMStableMenu.Text = 'View MS table';
            % Assign app.ExperimentContextMenu
            app.ExpTable.ContextMenu = app.ExperimentContextMenu;
            app.ContextMenuRun = uicontextmenu(app.OpenMebius2UIFigure);
            app.AddbatchMenu = uimenu(app.ContextMenuRun);
            app.AddbatchMenu.MenuSelectedFcn = createCallbackFcn(app, @RunAddbatchMenuSelected, true);
            app.AddbatchMenu.Text = 'Add batch';
            app.RemovethisbatchMenu = uimenu(app.ContextMenuRun);
            app.RemovethisbatchMenu.MenuSelectedFcn = createCallbackFcn(app, @RunRemovethisbatchMenuSelected, true);
            app.RemovethisbatchMenu.Text = 'Remove this batch';
            app.ParallellabelingMenu = uimenu(app.ContextMenuRun);
            app.ParallellabelingMenu.MenuSelectedFcn = createCallbackFcn(app, @RunParallellabelingMenuSelected, true);
            app.ParallellabelingMenu.Text = 'Parallel labeling';
            % Assign app.ContextMenuRun
            app.RunTable.ContextMenu = app.ContextMenuRun;
            app.ContextMenu2 = uicontextmenu(app.OpenMebius2UIFigure);
            app.RelativetoMenu = uimenu(app.ContextMenu2);
            app.RelativetoMenu.MenuSelectedFcn = createCallbackFcn(app, @RelativetoMenuSelected, true);
            app.RelativetoMenu.Text = 'Relative to ...';
            % Assign app.ContextMenu2
            app.ResultMainTable.ContextMenu = app.ContextMenu2;
            app.ContextMenuResultSelect = uicontextmenu(app.OpenMebius2UIFigure);
            app.RangeplotMenu = uimenu(app.ContextMenuResultSelect);
            app.RangeplotMenu.MenuSelectedFcn = createCallbackFcn(app, @RangeplotMenuSelected, true);
            app.RangeplotMenu.Text = 'Range plot';
            app.ViewsuggestionMenu = uimenu(app.ContextMenuResultSelect);
            app.ViewsuggestionMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewsuggestionMenuSelected, true);
            app.ViewsuggestionMenu.Text = 'View suggestion';
            % Assign app.ContextMenuResultSelect
            app.ResultSubTable.ContextMenu = app.ContextMenuResultSelect;
            app.ContextMenu3 = uicontextmenu(app.OpenMebius2UIFigure);
            app.CopythistracerforallentriesMenu = uimenu(app.ContextMenu3);
            app.CopythistracerforallentriesMenu.MenuSelectedFcn = createCallbackFcn(app, @CopythistracerforallentriesMenuSelected, true);
            app.CopythistracerforallentriesMenu.Text = 'Copy this tracer for all entries';
            % Assign app.ContextMenu3
            app.LabelTable.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.OpenMebius2UIFigure.Visible = 'on';
        end

    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenMebius2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OpenMebius2UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end

        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OpenMebius2UIFigure)
        end

    end

end